classdef Clusterizer_v14_beta_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        setDIR              matlab.ui.container.Menu
        adv_opts            matlab.ui.container.Menu
        minDIST             matlab.ui.container.Menu
        CloseProgramMenu    matlab.ui.container.Menu
        RestartProgramMenu  matlab.ui.container.Menu
        uitoolbar1          matlab.ui.container.Toolbar
        loadTest            matlab.ui.container.toolbar.PushTool
        threshold           matlab.ui.container.toolbar.PushTool
        clusterSpk          matlab.ui.container.toolbar.PushTool
        deleteTrace         matlab.ui.container.toolbar.PushTool
        Clu6                matlab.ui.control.StateButton
        Clu5                matlab.ui.control.StateButton
        Clu4                matlab.ui.control.StateButton
        Clu3                matlab.ui.control.StateButton
        Clu2                matlab.ui.control.StateButton
        Clu1                matlab.ui.control.StateButton
        negaTh              matlab.ui.control.StateButton
        timeDur             matlab.ui.control.Label
        text10              matlab.ui.control.Label
        thTag               matlab.ui.control.Label
        ACTION              matlab.ui.control.Button
        subTask             matlab.ui.control.DropDown
        manThval            matlab.ui.control.Label
        manualThresh        matlab.ui.control.Slider
        expClus             matlab.ui.control.Button
        eleNUM              matlab.ui.control.Label
        ddepNUM             matlab.ui.control.Label
        dateNum             matlab.ui.control.Label
        infotext            matlab.ui.control.Label
        sdC6                matlab.ui.control.UIAxes
        sdC5                matlab.ui.control.UIAxes
        sdC4                matlab.ui.control.UIAxes
        sdC3                matlab.ui.control.UIAxes
        sdC2                matlab.ui.control.UIAxes
        sdC1                matlab.ui.control.UIAxes
        clust6              matlab.ui.control.UIAxes
        clust5              matlab.ui.control.UIAxes
        meanClusts          matlab.ui.control.UIAxes
        clust4              matlab.ui.control.UIAxes
        clust3              matlab.ui.control.UIAxes
        clust2              matlab.ui.control.UIAxes
        clust1              matlab.ui.control.UIAxes
        allClust            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        LoadLoc % Description
        DepthID
        DepthIND
        oriFload
        spikeDataRaw
        spkFS
        startT
        spkData
        Elnum
        minSPdist
        minSepDist
        waveForms
        XLIMa
        peakIndex
        threshUsed
        kmeansNUsed
        voltThresh
        cluMets
        numClusts
        actClIDS
        clustID
        features
        fedTablei
        mainFoldLoc
        fil2spkFold
        rawDatFold
        spkCluFold
        vcFile2load
        rawSELfold
        cas2use
    end
    
    methods (Access = private)
        function clearWavePlots(app)
            
            for ci = 1:6
                tmpClustIa = ['clust' , num2str(ci)];
                tmpClustIb = ['sdC' , num2str(ci)];
                cla(app.(tmpClustIa))
                app.(tmpClustIa).Color = [0.9412 0.9412 0.9412];
                app.(tmpClustIa).Title = [];
                
                cla(app.(tmpClustIb))
                app.(tmpClustIa).Color = [0.9412 0.9412 0.9412];
            end
            
            cla(app.meanClusts)
            app.meanClusts.Color = [0.9412 0.9412 0.9412];
        end
        
        function loadNEXTh(app)
            

            app.expClus.Enable = 'off';

            app.threshold.Enable = 'off';
            app.deleteTrace.Enable = 'off';
            app.subTask.Enable = 'off';
            app.loadTest.Enable = 'on';
            
            app.manualThresh.Enable = 'off';
            app.manualThresh.Value = 4;
            app.manThval.Text = '4';
            

            cla(app.allClust)
            title(app.allClust, '')
            cla(app.meanClusts)
            title(app.meanClusts, '')
            
            for ci = 1:6
                cla(app.(strcat('clust',num2str(ci))))
                title(app.(strcat('clust',num2str(ci))),'')
                cla(app.(strcat('sdC',num2str(ci))))
            end
            
            app.ddepNUM.Text = 'DONE';
            app.eleNUM.Text = 'LOAD NEXT!';
            app.infotext.Text = 'LOAD NEXT!';
        end
        
        function plotRastWaves(app, stage, sub)
            
            switch stage
                
                case 'new'
                    
                    % TIME
                    timeDurSpk = round(length(app.spikeDataRaw)/app.spkFS,2);
                    app.timeDur.Text = [num2str(timeDurSpk) , ' seconds'];
                    
                    cla(app.allClust)
                    app.allClust.Color = [0.9412 0.9412 0.9412];
                    app.allClust.Title = [];
                    
                case 'threshold'
                    
                    cla(app.allClust)
                    
                    % TIME
                    timeDurSpk = round(length(app.spikeDataRaw)/app.spkFS,2);
                    app.timeDur.Text = [num2str(timeDurSpk) , ' seconds'];
                    
                    app.allClust.Color = [0.9412 0.9412 0.9412];
                    app.allClust.Title = [];
                    
                    twaveForms = app.waveForms.allWaves.waves;
                    totLen = app.XLIMa;
                    
                    hold on
                    
                    axes(app.allClust)
                    
                    hold on
                    
                    if size(twaveForms,2) > 200
                        
                        rwInd = randi(size(twaveForms,2),1,200);
                        
                    else
                        
                        rwInd = 1:size(twaveForms,2);
                        
                    end
                    
                    for k = 1:length(rwInd)
                        tmpW = rwInd(k);
                        patchline(1:size(twaveForms,1),twaveForms(:,tmpW),'linestyle','-','edgecolor','k','linewidth',1,'edgealpha',0.05)
                    end
                    
                    xlim(app.allClust,[1 totLen]);
                    
                    if isempty(twaveForms)
                        ylim(app.allClust,[-1500 1500])
                    else
                        ylim(app.allClust,[min(twaveForms(:)) max(twaveForms(:))]);
                    end
                    
                    app.allClust.XTick = [];
                    app.allClust.YTick = [];
                    titleStr = ['All waveforms = ' , num2str(size(twaveForms,2))];
                    title(app.allClust,titleStr);
                    
                case '3'
                    
                    switch sub
                        case 'cluster'
                            
                            % Set up data to plot
                            tnumClusts = app.numClusts; % Single value with number of clusters
                            cluID = app.actClIDS; % Index vector (length of samples) with cluster IDs
                            cfilt = app.clustID; % Vector with actual cluster IDs
                            twaveForms = app.waveForms.allWaves.waves; % Waveforms
                            
                            messageUPDATE(app, [0 0.6 0], 'Clusters Done!')
                            
                        case 'temp-sing'
                            
                            % Set up data to plot
                            tnumClusts = app.numClusts;
                            cluID = app.actClIDS;
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Templates Done!')
                            
                        case 'combine-clus'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Clusters Combined!')
                            
                        case 'delete-clus'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Clusters Deleted!')
                            
                        case 'temp-inc'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Template Inclusive!')
                            
                        case 'temp-exc'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Template Exclusive!')
                            
                        case 'clust-sing'
                            
                            tnumClusts = app.numClusts;
                            cluID = unique(app.clustID(app.clustID > 0));
                            cfilt = app.clustID;
                            twaveForms = app.waveForms.allWaves.waves;
                            
                            messageUPDATE(app, [0 0.6 0], 'Re-Clustered!')
                            
                    end
                    
                    colors2 = [1 0 0 ;...
                        0 0 1 ;...
                        0 0.5 0 ;...
                        0.4941 0.1843 0.5569 ;...
                        0.8706 0.4902 0 ;...
                        1 0 1];
                    cla(app.meanClusts)
                    tyMax = 100;
                    tyMin = -100;
                    sdMAX = 100;
                    sdMIN = -100;
                    for ni = 1:tnumClusts
                        
                        cluN = cluID(ni);
                        
                        cla(app.(strcat('clust',num2str(ni))))
                        
                        c1Waves = twaveForms(:,cfilt == cluN);
                        
                        mW = mean(c1Waves,2);
                        sW = std(c1Waves,[],2);
                        uW = mW + (sW*2);
                        dW = mW - (sW*2);
                        
                        % Plot outliers
                        allc1Wvs = transpose(c1Waves);
                        
                        if size(allc1Wvs,1) < 30
                            toPlotOut = 1:size(allc1Wvs,1);
                        else
                            toPlotOut = randi(size(allc1Wvs,1),1,30);
                        end
                        
                        axes(app.(strcat('clust',num2str(ni))))
                        
                        hold on
                        plot(transpose(allc1Wvs(logical(toPlotOut),:)),'k','LineWidth',0.09)
                        
                        plot(mW,'Color',colors2(ni,:) ,'LineWidth',4)
                        plot(uW,'Color',colors2(ni,:) ,'LineStyle','--','LineWidth',2)
                        plot(dW,'Color',colors2(ni,:) ,'LineStyle','--','LineWidth',2)
                        
                        tWaveforms2 = twaveForms(:,cfilt == cluN);
                        
                        if isempty(tWaveforms2)
                            ylim([-300 300])
                        else
                            ylim([min(tWaveforms2(:)) max(tWaveforms2(:))]);
                        end
                        
                        % AXIS INFO FOR Individual cluster plots
                        xlim(app.(strcat('clust',num2str(ni))),[1 app.XLIMa]);
                        app.(strcat('clust',num2str(ni))).XTick = [];
                        app.(strcat('clust',num2str(ni))).YTick = [];
                        
                        numWaves = sum(cfilt == cluN);
                        
                        titleStr = ['Clust ', num2str(ni) , ' = '  , num2str(numWaves)];
                        title(app.(strcat('clust',num2str(ni))),titleStr,'Color',colors2(ni,:));
                        
                        axes(app.meanClusts)
                        hold on
                        plot(mean(twaveForms(:,cfilt == cluN),2),'Color',colors2(ni,:),'LineWidth',2.5)
                        % UPDATED min and max
                        aveWave = mean(twaveForms(:,cfilt == cluN),2);
                        sdWave = std(twaveForms(:,cfilt == cluN),[],2);
                        UPwave = aveWave + sdWave;
                        DNwave = aveWave - sdWave;
                        
                        baseX = 1:1:length(aveWave);
                        patX = [baseX fliplr(baseX)];
                        patY = [transpose(DNwave) fliplr(transpose(UPwave))];
                        
                        if min(aveWave) < tyMin
                            tyMin = min(aveWave);
                        end
                        
                        if max(aveWave) > tyMax
                            tyMax = max(aveWave);
                        end
                        
                        if min(patY) < sdMIN
                            sdMIN = min(patY);
                        end
                        
                        if max(patY) > sdMAX
                            sdMAX = max(patY);
                        end
                        
                        % AXIS INFO FOR Mean plot
                        ylim(app.meanClusts,[tyMin tyMax]);
                        xlim(app.meanClusts,[1 app.XLIMa]);
                        app.meanClusts.XTick = [];
                        app.meanClusts.YTick = [];
                        
                        cla(app.(strcat('sdC',num2str(ni))))
                        %                         axes(app.(strcat('sdC',num2str(ni))))
                        
                        patch(app.(strcat('sdC',num2str(ni))),patX,patY,colors2(ni,:),'EdgeColor','none','FaceAlpha',0.7);
                        
                        ylim(app.(strcat('sdC',num2str(ni))),[sdMIN sdMAX])
                        xlim(app.(strcat('sdC',num2str(ni))),[1 app.XLIMa])
                        app.(strcat('sdC',num2str(ni))).XTick = [];
                        app.(strcat('sdC',num2str(ni))).YTick = [];
                        
                    end
                    hold off
                    
                    
            end
        end
        
        
        function messageUPDATE(app, coLOR, mesG)
            
            app.infotext.Text = mesG;
            app.infotext.FontColor = coLOR;
            
        end
        
        
        function subSelectUpdate(app, listI ,messAGE, colOR, EnabT)
            
            app.(listI).Enable = EnabT;
            app.(listI).Text = messAGE;
            app.(listI).FontColor = colOR;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Clusterizer_v14_beta_OpeningFcn(app, varargin)
            
            app.minSPdist = 1.5;
            
        end

        % Button pushed function: ACTION
        function ACTION_Callback(app, event)
       
            subSELECT = app.subTask.Value;
            subSELECT = (strtrim(subSELECT));
            
            if strcmp(subSELECT,'Select...')
                
                subSelectUpdate(app, 'ACTION' ,'Subroutine', 'k','on')
                
                for uci = 1:6
                    
                    app.(['Clu',num2str(uci)]).Value = 0;
                    app.(['Clu',num2str(uci)]).Enable = 'off';
                    app.(['Clu',num2str(uci)]).FontColor = 'k';
                    
                end
                
                return
            end

            rdiOButons = zeros(6,1);
            
            for i = 1:6
                
                val = app.(['Clu',num2str(i)]).Value;
                if val
                    rdiOButons(i) = 1;
                end
            end
            
            if sum(rdiOButons) == 0
                
                % INITIATE BUTTONS
                app.expClus.Enable = 'on';
                app.threshold.Enable = 'off';
                app.clusterSpk.Enable = 'off';
                
                return
            else
                for uci = 1:6
                    
                    app.(['Clu',num2str(uci)]).Value = 0;
                    app.(['Clu',num2str(uci)]).Enable = 'off';
                    app.(['Clu',num2str(uci)]).FontColor = 'k';
                    
                end
            end
            
            
            switch subSELECT
                
                case 'COMBINE'
                    % Combine waveforms with indicies that match the two selected clusters
                    % Plot in the n - 1 window number
                    % Actual clusterIDS
                    
                    actClus = app.actClIDS;
                    cluAcom = [transpose(1:length(actClus)) , actClus];
                    
                    % First combine clusters
                    cmINDs2 = logical(rdiOButons(1:length(actClus)));
                    inds2com = cluAcom(cmINDs2,2);
                    
                    newClu = max(actClus) + 1;
                    
                    newClID = app.clustID;
                    for ii = 1:length(inds2com)
                        curIndsT = app.clustID == inds2com(ii);
                        newClID(curIndsT) = newClu;
                    end
                    
                    app.clustID = newClID;
                    app.numClusts = numel(unique(newClID(newClID > 0)));
                    app.actClIDS = unique(newClID(newClID > 0));
                    
                    % REPLOT SPIKE STUFF
                    messageUPDATE(app, 'r', 'UPDATING clusters...')
                    
                    cla(app.meanClusts);
                    
                    clearWavePlots(app)
                    
                    plotRastWaves(app, '3', 'combine-clus')
                    
                    app.infotext.Text = 'Clusters Combined!';

                case 'DELETE'
                    
                    % Combine waveforms with indicies that match the two selected clusters
                    % Plot in the n - 1 window number
                    % Actual clusterIDS
                    actClus = app.actClIDS;
                    cluAcom = [transpose(1:length(actClus)) , actClus];
                    
                    % First combine clusters
                    delINDs2 = logical(rdiOButons(1:length(actClus)));
                    inds2del = cluAcom(delINDs2,2);
                    
                    newClID = app.clustID;
                    for ii = 1:length(inds2del)
                        
                        curIndsT = app.clustID == inds2del(ii);
                        
                        newClID(curIndsT) = 0;
                        
                    end
                    
                    app.clustID = newClID;
                    app.numClusts = numel(unique(newClID(newClID > 0)));
                    app.actClIDS = unique(newClID(newClID > 0));
                    
                    % REPLOT SPIKE STUFF
                    messageUPDATE(app, 'r', 'UPDATING clusters...')
                    cla(app.meanClusts);
                    clearWavePlots(app)
                    plotRastWaves(app, '3', 'delete-clus')
                    app.infotext.Text = 'Clusters Deleted!';
                    
                case 'TEMPLATE-Single'
                    
                    % Available Cluster IDS
                    actClus = app.actClIDS;
                    % Get Cluster ID to change
                    cmINDs2 = logical(rdiOButons(1:length(actClus)));
                    inds2refine = actClus(cmINDs2);
                    num2refine = numel(inds2refine);
                    
                    % Actual index for first block
                    for cci = 1:num2refine
                        curIndsT = find(app.clustID == inds2refine(cci));
                        
                        % Actual waves
                        actwaves = app.waveForms.allWaves.waves(:,app.clustID == inds2refine(cci));

                        messageUPDATE(app, 'r', 'Template Matching...')
  
                        newcluInds = app.clustID;
  
                        [corInds] = computeASCI_Clz_v05(actwaves); % was v2
                        keepInds = curIndsT(corInds);
                        excluInds = curIndsT(~(corInds));
                        
                        newcluInds(keepInds) = inds2refine(cci);
                        nActClu = max(actClus) + 1;
                        newcluInds(excluInds) = nActClu;
                        actClus = [actClus ; nActClu]; %#ok<AGROW>

                        nuClid1 = unique(newcluInds);
                        nuClid2 = nuClid1(nuClid1 ~= 0);
                        % Overwrite DATA
                        % Cluster IDS
                        % CHECK cluster NUMBER
                        if numel(nuClid2) > 6
                            
                            cla(app.meanClusts);
                            
                            plotRastWaves(app, '3', 'temp-sing')
                            warndlg('Too many clusters')
                            break

                        else
                            
                            app.clustID = newcluInds;
                            app.actClIDS = nuClid2;
                            app.numClusts = numel(nuClid2);
                            
                            cla(app.meanClusts);
                            
                            plotRastWaves(app, '3', 'temp-sing')
                        end
                        
                    end

                    app.infotext.Text = 'Templates Refined!';
   
                case 'TEMPLATE-Inclusive'
                    
                    actClus = app.actClIDS;
                    addClust = max(actClus) + 1;
                    % First combine clusters
                    cmINDs2 = logical(rdiOButons(1:length(actClus)));
                    keepClusts = actClus(~cmINDs2);
                    
                    inds2refine = actClus(cmINDs2);
                    
                    allwaves = app.waveForms.allWaves.waves;
                    
                    tmpCluInds = app.clustID;
                    tmpCluInds(tmpCluInds == 0) = addClust;
                    
                    cInds2INCrefine = [addClust ; inds2refine];
                    tINC_cIND = tmpCluInds;
                    tINC_cIND(~ismember(tINC_cIND,cInds2INCrefine)) = 0;
                    
                    [corInds] = computeASCI_Clz_v01a(allwaves, tINC_cIND);
                    
                    % Get indices for kept clusters
                    keptTemp = app.clustID;
                    keptTemp(~ismember(keptTemp,keepClusts)) = 0;
                    
                    % Combine
                    newTSingCls = keptTemp;
                    newTSingCls(corInds ~= 0) = corInds(corInds ~= 0);
                    
                    app.clustID = newTSingCls;
                    app.actClIDS = unique(newTSingCls(newTSingCls ~= 0));
                    app.numClusts = numel(app.actClIDS);
                    
                    cla(app.meanClusts);
                    
                    plotRastWaves(app, app, '3', 'temp-inc') %%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    app.infotext.Text = 'Templates-INC Refined!';
                    
                    
                case 'TEMPLATE-Exclusive'
                    
                    actClus = app.actClIDS;
                    
                    cmINDs2 = logical(rdiOButons(1:length(actClus)));
                    
                    keepClusts = actClus(~cmINDs2);
                    
                    inds2refine = actClus(cmINDs2);
                    
                    allwaves = app.waveForms.allWaves.waves;
                    tmpCluInds = app.clustID;
                    
                    tINC_cIND = tmpCluInds;
                    tINC_cIND(~ismember(tINC_cIND,inds2refine)) = 0;
                    
                    [corInds] = computeASCI_Clz_v01ex(allwaves, tINC_cIND);
                    
                    % Get indices for kept clusters
                    keptTemp = app.clustID;
                    keptTemp(~ismember(keptTemp,keepClusts)) = 0;
                    
                    % Combine
                    newTSingCls = keptTemp;
                    newTSingCls(corInds ~= 0) = corInds(corInds ~= 0);
                    
                    app.clustID = newTSingCls;
                    app.actClIDS = unique(newTSingCls(newTSingCls ~= 0));
                    app.numClusts = numel(app.actClIDS);
                    
                    cla(app.meanClusts);
                    
                    plotRastWaves(app, '3', 'temp-exc') %%%%%%%%%%%%%%%%%%%%%%%%%%
                    
                    app.infotext.Text = 'Templates-EXC Refined!';
                    
                case 'CLUSTER-Single'
                    
                    actClus = app.actClIDS;
                    
                    cmINDs2 = logical(rdiOButons(1:length(actClus)));
                    
%                     keepClusts = actClus(~cmINDs2);
                    
                    inds2refine = actClus(cmINDs2);
                    
                    allwaves = app.waveForms.allWaves.waves;
                    
                    tmpCluInds = app.clustID;
                    
                    Fs = app.spkFS;
                   
                    [ newIndex , exceedflag ] = clusterSingle_v2( allwaves, tmpCluInds, inds2refine, Fs);
                    
                    if ~exceedflag
                        
                        app.clustID = newIndex;
                        app.actClIDS = unique(newIndex(newIndex ~= 0));
                        app.numClusts = numel(app.actClIDS);
                        
                        cla(app.meanClusts);
                        
                        plotRastWaves(app, '3', 'clust-sing') %%%%%%%%%%%%%%%%%%%%%%%%%%
                        
                        app.infotext.Text = 'New clusters created!';
                        
                    else
                        
                        warndlg('Too many clusters')
                        
                    end
                    
            end
            
            app.subTask.Value = 'Select...';
            subSelectUpdate(app, 'ACTION' ,'Subroutine', 'k','off')

        end

        % Clicked callback: clusterSpk
        function clusterSpk_ClickedCallback(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % THIRD STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % CLUSTER WAVEFORMS
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            messageUPDATE(app, [1 0 0], 'Clustering...')
            
            twaveForms = app.waveForms.allWaves.waves;
            
            if size(twaveForms,2) >= 5
                
                [ xFeats ] = createSpkFeatures( twaveForms,  app.spkFS  );
                
                [idxF,Cen] = kmeans(xFeats, app.kmeansNUsed,'Distance','cityblock',...
                    'Replicates',6);
                
                [ nidx , app.cluMets , ~] = spkClusterQual( xFeats, idxF, Cen, 2 );
                
                tnumClusts = numel(unique(nidx));
                app.numClusts = tnumClusts;
                
                cluID = unique(nidx);
                app.actClIDS = cluID;
                
                cfilt = nidx;
                app.clustID = cfilt;
                app.features = xFeats;
            else
                app.numClusts = 1;
                app.actClIDS = 1;
                app.clustID = 1;
                app.features = nan;
            end
            
            cla(app.meanClusts);
            
            clearWavePlots(app)
            
            plotRastWaves(app, '3', 'cluster')
            
            messageUPDATE(app, [0 0.4980 0], 'Clusters Found!')
            
            %%% Following Thresholding
            % 1. Make Combine available
            % 2. Make Delete available
            % 3. Make Template available
            % 4. Close cluster
            
            % INITIATE BUTTONS
            
            app.expClus.Enable = 'on';
            app.threshold.Enable = 'off';
            app.clusterSpk.Enable = 'off';
            
            app.subTask.Enable = 'on';
            
        end

        % Clicked callback: deleteTrace
        function deleteTrace_ClickedCallback(app, event)

            tempTable = app.fedTablei;
            
%             eleUPdateNum = app.fedTablei.ElNum{app.DepthIND};
            
            keepInd = true(height(tempTable),1);
            
            keepInd(app.DepthIND) = false;
            
            app.fedTablei = tempTable(keepInd,:);
            
            cd(app.fil2spkFold)
            % Update table
            
            % load in old table
            % compare and save
            
            %%%%%
            % UPDATE app.Elnum 
            % DepthID
            % DepthIND
            
%             depUPdateInd = ismember(app.DepthID,app.activeFileName);
            
%             ele2ch = str2double(eleUPdateNum(length(eleUPdateNum)));
            
%             app.DepthIND{depUPdateInd,1}(ele2ch) = 0;

            % Resave file with appended table
            fedTable = app.fedTablei;
            save(app.vcFile2load, 'fedTable','-append')
            %%%% UPDATE
            
            % (4) Load next file fedTable.Depth{app.DepthIND};
%             app.activeFileNum = app.activeFileNum;
            
            if app.DepthIND > height(app.fedTablei)
                
                app.allClust.Visible = 'off';
                
                for ci = 1:6
                    app.(['clust',num2str(ci)]).Visible = 'off';
                end
   
                app.meanClusts.Visible = 'off';
                app.expClus.Enable = 'off';
%                 app.template.Enable = 'off';
                app.clusterSpk.Enable = 'off';
%                 app.delClusts.Enable = 'off';
                app.threshold.Enable = 'off';
                app.deleteTrace.Enable = 'off';
                app.loadTest.Enable = 'on';
                
                app.manualThresh.Enable = 'off';
                app.manualThresh.Value = 4;
                app.manThval.Text = '4';
                
                clearWavePlots(app)
                
                app.ddepNUM.Text = 'DONE';
                app.eleNUM.Text = 'LOAD NEXT!';
                app.infotext.Text = 'LOAD NEXT!';
                
            else
                
                app.DepthID = app.fedTablei.Depth{app.DepthIND};
                
                messageUPDATE(app, 'r','Loading next cell...')
             
                cd(app.rawSELfold);
                load(app.DepthID);
                app.spkFS = mer.sampFreqHz;
                
                app.spikeDataRaw = eval(app.fedTablei.ElNum{app.DepthIND});
                
                messageUPDATE(app, [0 0.4980 0],'Next File Loaded!!')

                app.ddepNUM.Text = app.DepthID;
                app.eleNUM.Text = app.fedTablei.ElNum{app.DepthIND};
                
                clearWavePlots(app)
                plotRastWaves(app, 'new')
                
            end
            
            % Reset Manual Threshold
            app.manualThresh.Enable = 'off';
            app.manualThresh.Value = 4;
            app.manThval.Text = '4';
            
            % Reset Buttons
            app.threshold.Enable = 'on';
            app.clusterSpk.Enable = 'off';
            app.deleteTrace.Enable = 'off';

        end

        % Button pushed function: expClus
        function expClus_Callback(app, event)

            % If current file is equal to the total number of files then shut down
            % program and re-initate load file button
            
            % (1) Export DATA
            %     (a) Export location
            %     (b) Export name
            %     (c) Bundle up export data
            %     (d) Save data
            
            % (a)

            cd(app.spkCluFold);
            
            eleNUMs = app.eleNUM.Text(length(app.eleNUM.Text));
            teleNUM = str2double(app.eleNUM.Text(length(app.eleNUM.Text)));
            
            abNparts = strsplit(app.DepthID,{'.','_'});
            
            depthN = abNparts{3};
            
            if strcmp(abNparts{1}(1),'A')
                abNUM = ['a',abNparts{2}];
            else
                abNUM = ['b',abNparts{2}];
            end
            
            saveNAME = ['spkData_',abNUM,'_',depthN,'_',eleNUMs,'_clzD.mat'];
            
            % (i)
            spikeDATA.clustIDS = app.clustID;
            % (ii)
            spikeDATA.waveforms = app.waveForms;
            % (iii)
            spikeDATA.thresholdINFO.thresholdSD = app.threshUsed;
            spikeDATA.thresholdINFO.kmeansN = app.kmeansNUsed;
            % (iv)
            spikeDATA.clustMetrics = app.cluMets;
            % (v)
            spikeDATA.features = app.features;
            spikeDATA.merFs = app.spkFS;
            % (vi)
            
            spikeDATA.fileINFO.abNUM = abNUM;
            spikeDATA.fileINFO.depthN = depthN;
            spikeDATA.fileINFO.eleNUM = teleNUM;
            spikeDATA.fileINFO.analyzDATE = date;
            % (vii)
            spikeDATA.numClusts = app.numClusts;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % ADD output for Andy Auditory taskbase
            % need time, threshold points well isolated
            spikeDATA.atIO.spFS = app.spkFS;
            spikeDATA.atIO.sptimeST = app.startT;
            spikeDATA.atIO.timestamps = app.waveForms.allWavesInfo.alllocs;
            spikeDATA.atIO.clustIDs = app.clustID;
            % (d)
            save(saveNAME,'spikeDATA');
            
            % (2) Modify buttons - to reset threshold and cluster
            app.threshold.Enable = 'on';
            app.clusterSpk.Enable = 'off';
            app.deleteTrace.Enable = 'off';
            app.expClus.Enable = 'off';
            app.subTask.Enable = 'off';
            
            % (3) Update table, append table to save file
            cd(app.fil2spkFold)
            app.fedTablei.Analyzed(app.DepthIND) = true;
            fedTable = app.fedTablei;
            save(app.vcFile2load,'fedTable','-append')
            
            if app.DepthIND == height(app.fedTablei)
                
                loadNEXTh(app)
                
            else
                
                % (4) Load next file
                app.DepthIND = app.DepthIND + 1;
                app.DepthID = app.fedTablei.Depth{app.DepthIND};
                
                messageUPDATE(app, 'r', 'Loading next cell...')
                
                cd(app.rawSELfold);
                load(app.DepthID);
                app.spkFS = mer.sampFreqHz;
                
                % NEW 06/26/2020
   
                app.spikeDataRaw = eval(app.Elnum);

                messageUPDATE(app, [0 0.4980 0] ,  'Next File Loaded!!')
                
                app.ddepNUM.Text = app.DepthID;

                app.eleNUM.Text = app.Elnum;
                % Clear figures

                clearWavePlots(app)
                plotRastWaves(app,'new')
                    

            end

        end

        % Clicked callback: loadTest
        function loadTest_ClickedCallback(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % FIRST STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % LOAD FILE
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 1. CD to Files 2 spikes2cluster folder 
            % 2. Select a mat
            % 3. Load mat file
            % 4. CD to Raw folder / Case folder
            % 5. Load first file from fedTable
            cd(app.fil2spkFold)
            
            allLIST1 = dir('*.mat');
            allLIST2 = {allLIST1.name};

            [indx] = listdlg('ListString',allLIST2);
            
            app.vcFile2load = allLIST2{indx};
            
            load(app.vcFile2load, "fedTable" , "CaseNum")
            
            app.cas2use = CaseNum(4:18);
            
            app.fedTablei = fedTable;
            
            startIND = find(~app.fedTablei.Analyzed,1,"first");
            
            app.DepthIND = startIND;
            app.DepthID = fedTable.Depth{app.DepthIND};
            app.Elnum = fedTable.ElNum{app.DepthIND};

            % Go to folder location
            % Load in first electrode
            app.rawSELfold = [app.rawDatFold , filesep , app.cas2use];
            cd(app.rawSELfold)
            
            load(app.DepthID,"mer",app.Elnum)
            
            app.spikeDataRaw = eval(app.Elnum);
            app.spkFS = mer.sampFreqHz;
            app.startT = mer.timeStart;
            
            % UpdateINFO
            app.ddepNUM.Text = app.DepthID;
            app.eleNUM.Text = app.Elnum;
            app.dateNum.Text = app.cas2use;
            
            app.infotext.Text = 'Loading files...';
            app.infotext.FontColor = 'r';
            drawnow;
            
            app.infotext.Text = 'File Loaded!!';
            app.infotext.FontColor = [0 0.4980 0];
            drawnow;
            
            plotRastWaves(app, 'new')
            
            % INITIATE buttons
            app.threshold.Enable = 'on';
            app.negaTh.Visible = 'on';
            app.loadTest.Enable = 'off';
            
        end

        % Value changed function: manualThresh
        function manualThresh_Callback(app, event)
            
            app.clusterSpk.Enable = 'on';
            app.expClus.Enable = 'off';
            %             handles.combineTog.Enable = 'off';
            %             handles.deleteTog.Enable = 'off';
            %             handles.refineTog.Enable = 'off';
            
            app.manThval.Text = num2str(app.manualThresh.Value);
            % Recompute threshold
            
            sampData = app.spikeDataRaw;
            tspkData = double(sampData);
            sampLen = round(app.spkFS/1000);
            
            messageUPDATE(app, 'm', 'Finding Peaks...')
            
            thresh2use = app.manualThresh.Value;
            
            thresh = (round(std(double(sampData)) * thresh2use) + mean(double(sampData)));
            noise = (round(std(double(sampData)) * 20) + mean(double(sampData)));
            minDist = round(app.spkFS/1000) * app.minSPdist;
            
            %%% Check threshold %%%%%%%%%%
            threshFlag = app.negaTh.Value;
            if threshFlag == 0
                pnFlag2 = 1;
            else
                pnFlag2 = 0;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [ waveData ] = extractWaveforms_Clz_v06(tspkData, thresh, noise, minDist, app.spkFS, pnFlag2); %%%%%%%%%%%%%%%%%%%%%%% PNFLAG
            
            app.infotext.Text = 'Waves Extracted...';
            app.infotext.FontColor = 'b';
            
            twaveForms = waveData.allWaves.waves;
            waveLocs = waveData.allWavesInfo.alllocs;
            
            totLen = length(1 - (sampLen/2):1 + (round(sampLen*0.8)-1));
            
            % Consider Transparent
            
            titleStr = ['All waveforms = ' , num2str(size(twaveForms,2))];
            title(titleStr);
            
            timeDone = toc;
            
            app.threshUsed = thresh2use;
            app.kmeansNUsed = 3;
            app.spkData = tspkData;
            app.voltThresh = thresh;
            app.minSepDist = minDist;
            app.waveForms = waveData;
            app.XLIMa = totLen;
            app.peakIndex = waveLocs;
            
            app.infotext.Text = ['Thresholding Done!' , '  time elapsed: ',...
                num2str(round(timeDone)), ' s'] ;
            app.infotext.FontColor = [0 0.4980 0];
            
            % Initiate buttons
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            app.threshold.Enable = 'off';
            % handles.clusterSpk.Enable = 'on';
            % handles.deleteTrace.Enable = 'on';
            app.expClus.Enable = 'off';
            
            clearWavePlots(app);
            
            plotRastWaves(app, 'new')
            plotRastWaves(app, 'threshold')
            
            % handles.clusterSpk.Enable = 'on';
            % handles.deleteTrace.Enable = 'on';
            
        end

        % Menu selected function: minDIST
        function minDIST_Callback(app, event)
            
            prompt = {'Min Distance btwn spikes (ms)'};
            dlg_title = 'Min Dist.';
            num_lines = [1 35];
            defaultans = {num2str(app.minSPdist)};
            answer = inputdlg(prompt,dlg_title,num_lines,defaultans);
            
            app.minSPdist = str2double(answer{1});
            
        end

        % Value changed function: subTask
        function subTask_Callback(app, event)
            
            subSELECT = app.subTask.Value;
            subSELECT = (strtrim(subSELECT));
            
            if strcmp(subSELECT,'Select...')
                subSelectUpdate(app, 'ACTION' ,'Subroutine', 'k','on')
                for uci = 1:6
                    
                    app.(['Clu',num2str(uci)]).Value = 0;
                    app.(['Clu',num2str(uci)]).Enable = 'off';
                    app.(['Clu',num2str(uci)]).FontColor = 'k';
                    
                end
                
                return
            end
            
            for uci = 1:6
                
                app.(['Clu',num2str(uci)]).Value = 0;
                app.(['Clu',num2str(uci)]).Enable = 'off';
                app.(['Clu',num2str(uci)]).FontColor = 'k';
                
            end
            
            colorS = [1 0 0 ;...
                0 0 1 ;...
                0 0.4980 0 ;...
                0.4941 0.1843 0.5569 ;...
                0.8706 0.4902 0 ;...
                1 0 1];
            
            for nii = 1:app.numClusts
                
                app.(['Clu',num2str(nii)]).Enable = 'on';
                app.(['Clu',num2str(nii)]).FontColor = colorS(nii,:);
                
            end
            
            % INITIATE BUTTONS
            app.expClus.Enable = 'on';
            app.threshold.Enable = 'off';
            app.clusterSpk.Enable = 'off';
            
            switch subSELECT
                
                case 'COMBINE'
                    subSelectUpdate(app, 'ACTION', 'Combine Clusters', 'k', 'on')
                case 'DELETE'
                    subSelectUpdate(app, 'ACTION', 'Delete Clusters', [0.529 0.318 0.318], 'on')
                case 'TEMPLATE-Single'
                    subSelectUpdate(app, 'ACTION', 'T-Refine Clusters', [0.0 0.447 0.741], 'on')
                case 'TEMPLATE-Inclusive'
                    subSelectUpdate(app, 'ACTION', 'T-Inclusive (0&1)', [0.467 0.675 0.188], 'on')              
                case 'TEMPLATE-Exclusive'
                    subSelectUpdate(app, 'ACTION', 'T-Exclusive (1)', [0.0 0.6 0.6], 'on')                  
                case 'CLUSTER-Single'
                    subSelectUpdate(app, 'ACTION', 'Cluster', [1 0.1 0.1], 'on')
   
            end
            
        end

        % Clicked callback: threshold
        function threshold_ClickedCallback(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % SECOND STEP OF APP
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % THRESHOLD TRACE
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            sampData = app.spikeDataRaw;
            tspkData = double(sampData);
            sampLen = round(app.spkFS/1000);
            
            app.infotext.Text = 'Estimating Threshold...';
            app.infotext.FontColor = 'r';
            
            thresh = (round(std(double(sampData)) * 3) + mean(double(sampData)));
            noise = (round(std(double(sampData)) * 20) + mean(double(sampData)));
            minDist = round(app.spkFS/1000) * app.minSPdist; % was 1.5
            
            tic;
            
            %%% Check threshold
            
            threshFlag = app.negaTh.Value;
            if threshFlag == 0
                pnFlag2 = 1;
            else
                pnFlag2 = 0;
            end
            
            %%%%%%%%%%%%%%%%%%%%
            
            [ waveData ] = extractWaveforms_Clz_v06(tspkData, thresh, noise, minDist, app.spkFS, pnFlag2); %%%%%%%%%%%%%%%%%%%%% PNFLAG
            app.waveForms = waveData;
            
            app.infotext.Text = 'Waves Extracted...';
            app.infotext.FontColor = 'b';
            
            app.peakIndex = waveData.allWavesInfo.alllocs;
            
            totLen = length(1 - (sampLen/2):1 + (round(sampLen*0.8)-1));
            app.XLIMa = totLen;
            
            timeDone = toc;
            
            app.threshUsed = 3;
            app.kmeansNUsed = 3;
            app.spkData = tspkData;
            app.voltThresh = thresh;
            app.minSepDist = minDist;
            
            plotRastWaves(app, 'threshold')
            
            app.infotext.Text = ['Thresholding Done!' , '  time elapsed: ', num2str(round(timeDone)), ' s'] ;
            app.infotext.FontColor = [0 0.4980 0];
            
            %%% Following Thresholding
            % 1. Make Clustering available
            % 2. Manual Thresholing available
            % 3. Abort analysis on bad cell
            
            % Initiate buttons
            app.threshold.Enable = 'off';
            app.clusterSpk.Enable = 'on';
            %             app.template.Enable = 'off';
            %             app.combineClus.Enable = 'off';
            
            % Indicate threshold in window
            
            app.manThval.Text = num2str(3);
            app.manualThresh.Value = 3;
            app.manualThresh.Enable = 'on';
            % Activate manual override
            
            % Activate emergency delete
            
            app.deleteTrace.Enable = 'on';
        end

        % Menu selected function: CloseProgramMenu
        function CloseProgramMenuSelected(app, event)
            delete(app)
        end

        % Menu selected function: RestartProgramMenu
        function RestartProgramMenuSelected(app, event)
            delete(app)
            Clusterizer_v14_beta_App()
        end

        % Menu selected function: setDIR
        function setDIRMenuSelected(app, event)
            fileCname = ['VC_',sprintf('%X','FileLoc'),'.txt'];
            fileCheck = string(fileCname);

            % Identify location of fileCheck
            [textLocFlag , app.mainFoldLoc] = uigetfile('*.txt');

            while textLocFlag == 0
                [textLocFlag , app.mainFoldLoc] = uigetfile('*.txt');
            end

            textLF = string(textLocFlag);
            if fileCheck ~= textLF
                return
            end

            app.loadUIprog = uiprogressdlg(app.figure1,'Title','LOADING Files',...
                'Message','Loading files','Indeterminate','on');
            drawnow

            % rawDataFold = [app.mainFoldLoc , filesep , 'RAW_Ephys_Files', filesep];
            app.fil2spkFold = [app.mainFoldLoc , filesep , 'SpikeDates2Cluster', filesep];
            app.rawDatFold =  [app.mainFoldLoc , filesep , 'RAW_Ephys_Files', filesep];
            app.spkCluFold =  [app.mainFoldLoc , filesep , 'ClusteredSpikeTimes', filesep];
            
            
            app.loadTest.Enable = 'on';

            close(app.loadUIprog)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Clusterizer_v14_beta';
            app.figure1.Tag = 'figure1';
            app.setDIR = uimenu(app.figure1);
            app.setDIR.MenuSelectedFcn = createCallbackFcn(app, @setDIRMenuSelected, true);
            app.setDIR.Text = 'SET DIRECTORY';
            app.adv_opts = uimenu(app.figure1);
            app.adv_opts.Text = 'Advanced Options';
            app.adv_opts.Tag = 'adv_opts';
            app.minDIST = uimenu(app.adv_opts);
            app.minDIST.MenuSelectedFcn = createCallbackFcn(app, @minDIST_Callback, true);
            app.minDIST.Text = 'Min Dist';
            app.minDIST.Tag = 'minDIST';
            app.CloseProgramMenu = uimenu(app.figure1);
            app.CloseProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseProgramMenuSelected, true);
            app.CloseProgramMenu.Text = 'Close Program';
            app.RestartProgramMenu = uimenu(app.figure1);
            app.RestartProgramMenu.MenuSelectedFcn = createCallbackFcn(app, @RestartProgramMenuSelected, true);
            app.RestartProgramMenu.Text = 'Restart Program';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.loadTest = uipushtool(app.uitoolbar1);
            app.loadTest.Tag = 'loadTest';
            app.loadTest.ClickedCallback = createCallbackFcn(app, @loadTest_ClickedCallback, true);
            app.loadTest.Enable = 'off';
            app.loadTest.Icon = 'loadTest_image.png';
            app.threshold = uipushtool(app.uitoolbar1);
            app.threshold.Tag = 'threshold';
            app.threshold.ClickedCallback = createCallbackFcn(app, @threshold_ClickedCallback, true);
            app.threshold.Enable = 'off';
            app.threshold.Icon = 'threshold_image.png';
            app.clusterSpk = uipushtool(app.uitoolbar1);
            app.clusterSpk.Tag = 'clusterSpk';
            app.clusterSpk.ClickedCallback = createCallbackFcn(app, @clusterSpk_ClickedCallback, true);
            app.clusterSpk.Enable = 'off';
            app.clusterSpk.Icon = 'clusterSpk_image.png';
            app.deleteTrace = uipushtool(app.uitoolbar1);
            app.deleteTrace.Tag = 'deleteTrace';
            app.deleteTrace.ClickedCallback = createCallbackFcn(app, @deleteTrace_ClickedCallback, true);
            app.deleteTrace.Enable = 'off';
            app.deleteTrace.Icon = 'deleteTrace_image.png';
            app.allClust = uiaxes(app.figure1);
            app.allClust.XTick = [];
            app.allClust.XTickLabel = {'0'; '0.5'; '1'};
            app.allClust.YTick = [];
            app.allClust.YTickLabel = {'0'; '0.5'; '1'};
            app.allClust.Tag = 'allClust';
            app.clust1 = uiaxes(app.figure1);
            app.clust1.XTick = [];
            app.clust1.XTickLabel = '';
            app.clust1.YTick = [];
            app.clust1.YTickLabel = '';
            app.clust1.Tag = 'clust1';
            app.clust2 = uiaxes(app.figure1);
            app.clust2.XTick = [];
            app.clust2.XTickLabel = '';
            app.clust2.YTick = [];
            app.clust2.YTickLabel = '';
            app.clust2.Tag = 'clust2';
            app.clust3 = uiaxes(app.figure1);
            app.clust3.XTick = [];
            app.clust3.XTickLabel = '';
            app.clust3.YTick = [];
            app.clust3.YTickLabel = '';
            app.clust3.Tag = 'clust3';
            app.clust4 = uiaxes(app.figure1);
            app.clust4.XTick = [];
            app.clust4.XTickLabel = '';
            app.clust4.YTick = [];
            app.clust4.YTickLabel = '';
            app.clust4.Tag = 'clust4';
            app.meanClusts = uiaxes(app.figure1);
            app.meanClusts.XTick = [];
            app.meanClusts.XTickLabel = '';
            app.meanClusts.YTick = [];
            app.meanClusts.YTickLabel = '';
            app.meanClusts.Tag = 'meanClusts';
            app.clust5 = uiaxes(app.figure1);
            app.clust5.XTick = [];
            app.clust5.XTickLabel = '';
            app.clust5.YTick = [];
            app.clust5.YTickLabel = '';
            app.clust5.Tag = 'clust5';
            app.clust6 = uiaxes(app.figure1);
            app.clust6.XTick = [];
            app.clust6.XTickLabel = '';
            app.clust6.YTick = [];
            app.clust6.YTickLabel = '';
            app.clust6.Tag = 'clust6';
            app.sdC1 = uiaxes(app.figure1);
            app.sdC1.XTick = [];
            app.sdC1.XTickLabel = '';
            app.sdC1.YTick = [];
            app.sdC1.YTickLabel = '';
            app.sdC1.Tag = 'sdC1';
            app.sdC2 = uiaxes(app.figure1);
            app.sdC2.XTick = [];
            app.sdC2.XTickLabel = {'0'; '0.5'; '1'};
            app.sdC2.YTick = [];
            app.sdC2.YTickLabel = {'0'; '0.5'; '1'};
            app.sdC2.Tag = 'sdC2';
            app.sdC3 = uiaxes(app.figure1);
            app.sdC3.XTick = [];
            app.sdC3.XTickLabel = {'0'; '0.5'; '1'};
            app.sdC3.YTick = [];
            app.sdC3.YTickLabel = {'0'; '0.5'; '1'};
            app.sdC3.Tag = 'sdC3';
            app.sdC4 = uiaxes(app.figure1);
            app.sdC4.XTick = [];
            app.sdC4.XTickLabel = {'0'; '0.5'; '1'};
            app.sdC4.YTick = [];
            app.sdC4.YTickLabel = {'0'; '0.5'; '1'};
            app.sdC4.Tag = 'sdC4';
            app.sdC5 = uiaxes(app.figure1);
            app.sdC5.XTick = [];
            app.sdC5.XTickLabel = {'0'; '0.5'; '1'};
            app.sdC5.YTick = [];
            app.sdC5.YTickLabel = {'0'; '0.5'; '1'};
            app.sdC5.Tag = 'sdC5';
            app.sdC6 = uiaxes(app.figure1);
            app.sdC6.XTick = [];
            app.sdC6.XTickLabel = {'0'; '0.5'; '1'};
            app.sdC6.YTick = [];
            app.sdC6.YTickLabel = {'0'; '0.5'; '1'};
            app.sdC6.Tag = 'sdC6';
            app.infotext = uilabel(app.figure1);
            app.infotext.Tag = 'infotext';
            app.infotext.Text = 'Program Info...';
            app.dateNum = uilabel(app.figure1);
            app.dateNum.Tag = 'dateNum';
            app.dateNum.Text = '00/00/0000_0';
            app.ddepNUM = uilabel(app.figure1);
            app.ddepNUM.Tag = 'ddepNUM';
            app.ddepNUM.Text = 'Trg_00_00000';
            app.eleNUM = uilabel(app.figure1);
            app.eleNUM.Tag = 'eleNUM';
            app.eleNUM.Text = 'Elec_0';
            app.expClus = uibutton(app.figure1, 'push');
            app.expClus.ButtonPushedFcn = createCallbackFcn(app, @expClus_Callback, true);
            app.expClus.Tag = 'expClus';
            app.expClus.Enable = 'off';
            app.expClus.Text = 'EXPORT Cluster!';
            app.manualThresh = uislider(app.figure1);
            app.manualThresh.Limits = [1 6];
            app.manualThresh.MajorTicks = [];
            app.manualThresh.ValueChangedFcn = createCallbackFcn(app, @manualThresh_Callback, true);
            app.manualThresh.MinorTicks = [];
            app.manualThresh.Tag = 'manualThresh';
            app.manualThresh.Enable = 'off';
            app.manualThresh.Value = 4;
            app.manThval = uilabel(app.figure1);
            app.manThval.Tag = 'manThval';
            app.manThval.Text = '';
            app.subTask = uidropdown(app.figure1);
            app.subTask.Items = {'Select...', 'COMBINE           ', 'DELETE            ', 'TEMPLATE-Single', 'CLUSTER-Single'};
            app.subTask.ValueChangedFcn = createCallbackFcn(app, @subTask_Callback, true);
            app.subTask.Tag = 'subTask';
            app.subTask.Enable = 'off';
            app.subTask.Value = 'Select...';
            app.ACTION = uibutton(app.figure1, 'push');
            app.ACTION.ButtonPushedFcn = createCallbackFcn(app, @ACTION_Callback, true);
            app.ACTION.Tag = 'ACTION';
            app.ACTION.Enable = 'off';
            app.ACTION.Text = 'SubRoutine';
            app.thTag = uilabel(app.figure1);
            app.thTag.Tag = 'thTag';
            app.thTag.Text = 'Threshold';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Time: ';
            app.timeDur = uilabel(app.figure1);
            app.timeDur.Tag = 'timeDur';
            app.timeDur.Text = '0';
            app.negaTh = uibutton(app.figure1, 'state');
            app.negaTh.Enable = 'off';
            app.negaTh.Text = 'Switch to Negative';
            app.Clu1 = uibutton(app.figure1, 'state');
            app.Clu1.Enable = 'off';
            app.Clu1.Text = 'CLUSTER 1';
            app.Clu2 = uibutton(app.figure1, 'state');
            app.Clu2.Enable = 'off';
            app.Clu2.Text = 'CLUSTER 2';
            app.Clu3 = uibutton(app.figure1, 'state');
            app.Clu3.Enable = 'off';
            app.Clu3.Text = 'CLUSTER 3';
            app.Clu4 = uibutton(app.figure1, 'state');
            app.Clu4.Enable = 'off';
            app.Clu4.Text = 'CLUSTER 4';
            app.Clu5 = uibutton(app.figure1, 'state');
            app.Clu5.Enable = 'off';
            app.Clu5.Text = 'CLUSTER 5';
            app.Clu6 = uibutton(app.figure1, 'state');
            app.Clu6.Enable = 'off';
            app.Clu6.Text = 'CLUSTER 6';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clusterizer_v14_beta_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Clusterizer_v14_beta_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end