classdef SpikeR_v07_beta_App5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uitoolbar1      matlab.ui.container.Toolbar
        loadFolder      matlab.ui.container.toolbar.PushTool
        closeProgram    matlab.ui.container.toolbar.PushTool
        exporT          matlab.ui.container.toolbar.PushTool
        appSel          matlab.ui.control.Button
        thrshSD         matlab.ui.control.Spinner
        SDThreshLabel   matlab.ui.control.Label
        QCpanel         matlab.ui.container.Panel
        E3Switch        matlab.ui.control.ToggleSwitch
        E3SwitchLabel   matlab.ui.control.Label
        E2Switch        matlab.ui.control.ToggleSwitch
        E2SwitchLabel   matlab.ui.control.Label
        E1Switch        matlab.ui.control.ToggleSwitch
        E1SwitchLabel   matlab.ui.control.Label
        seg_back        matlab.ui.control.Button
        seg_for         matlab.ui.control.Button
        text2           matlab.ui.control.Label
        NUMsegs         matlab.ui.control.Label
        next_file       matlab.ui.control.Button
        text7           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        caseNum         matlab.ui.control.Label
        depthVal        matlab.ui.control.Label
        curFile         matlab.ui.control.Label
        totFile         matlab.ui.control.Label
        text13          matlab.ui.control.Label
        messageTxt      matlab.ui.control.Label
        zoomOUT         matlab.ui.control.StateButton
        last_file       matlab.ui.control.Button
        RawTracesPanel  matlab.ui.container.Panel
        GridLayout      matlab.ui.container.GridLayout
        peak3           matlab.ui.control.UIAxes
        peak2           matlab.ui.control.UIAxes
        peak1           matlab.ui.control.UIAxes
        ele3win         matlab.ui.control.UIAxes
        ele2win         matlab.ui.control.UIAxes
        ele1win         matlab.ui.control.UIAxes
    end

    %%%% TO DO


    properties (Access = private)
        FolderMain % Description
        SaveLoc
        fNames
        FileTotal
        activeFile
        activeFileNum
        recFlag
        TOTel
        activeE
        yLIMs
        activeFR
        activeLength
        eleColor
        threshL
        rem
        minDist
        sampLen
        locs_Rwave
        peakP2rem
        peakN2rem
        ind2remPPks
        ind2remNPks
        locs_NGwave
        PwaveForms
        NwaveForms
        waveForms
        SpikeInfo
        totLen
        setNum
        surgNum
        MOVE
        curSeg
        curTHRESH
        zState
        allDAT
        caseID
        elecID
        curEledat
        segIDs
        newFS
        YLIMi
 
    end
    
    methods (Access = private)
        function compute_newWaves(app)

            for ii = 1:app.TOTel
                esingE = ['E',num2str(ii)];
  
                spkData = double(app.activeE.(['E',num2str(ii)]));
                smpData = app.activeE.(['E',num2str(ii)]);
                
                % Positive waves
                [app.locs_Rwave{ii} ,  ~] = peakseek(spkData , app.minDist , app.threshL.(esingE));
                
                % Negative waves
                ridP = smpData;
                ridP(ridP > 0) = 0;
                [app.locs_NGwave{ii} ,  ~] = peakseek(abs(double(ridP)) , app.minDist , app.threshL.(esingE));
                
                % Positive Noise
                [app.peakP2rem{ii} ,  ~] = peakseek(double(spkData) , app.minDist , app.rem.(esingE));
                
                app.ind2remPPks{ii} = ~ismember(app.locs_Rwave{ii},app.peakP2rem{ii});
                app.locs_Rwave{ii} = app.locs_Rwave{ii}(app.ind2remPPks{ii});
                app.locs_Rwave{ii} = app.locs_Rwave{ii}(app.locs_Rwave{ii} > app.sampLen);
                
                % Negative Noise
                [app.peakN2rem{ii} ,  ~] = peakseek(abs(double(ridP)) , app.minDist , app.rem.(esingE));
                
                app.ind2remNPks{ii} = ~ismember(app.locs_NGwave{ii},app.peakN2rem{ii});
                app.locs_NGwave{ii} = app.locs_NGwave{ii}(app.ind2remNPks{ii});
                app.locs_NGwave{ii} = app.locs_NGwave{ii}(app.locs_NGwave{ii} > app.sampLen);
                
                app.PwaveForms{ii} = zeros(app.totLen,length(app.locs_Rwave{ii}));
                for ai = 1:length(app.locs_Rwave{ii})
                    locP = app.locs_Rwave{ii}(ai);
                    sampPeriod = locP - (app.sampLen/2):locP + (round(app.sampLen*0.8)-1);
                    if any(sampPeriod > length(spkData))
                        app.PwaveForms{ii}(:,ai) = [];
                        break
                    else
                        app.PwaveForms{ii}(:,ai) = spkData(sampPeriod);
                    end
                end
                
                app.NwaveForms{ii} = zeros(app.totLen,length(app.locs_NGwave{ii}));
                for ai = 1:length(app.locs_NGwave{ii})
                    locP = app.locs_NGwave{ii}(ai);
                    sampPeriod = locP - (app.sampLen/2):locP + (round(app.sampLen*0.8)-1);
                    if any(sampPeriod > length(spkData))
                        app.NwaveForms{ii}(:,ai) = [];
                        break
                    else
                        app.NwaveForms{ii}(:,ai) = (spkData(sampPeriod))*-1;
                    end
                end
                
                %%% NEW CODE 5/18/2017
                tempAllWAVES = [app.NwaveForms{ii} , app.PwaveForms{ii}];
                if size(tempAllWAVES,2) <= 100
                    app.waveForms{ii} = [app.NwaveForms{ii} , app.PwaveForms{ii}];
                else
                    ind2use = randperm(size(tempAllWAVES,2),100);
                    app.waveForms{ii} = tempAllWAVES(:,ind2use);
                end
                
                %%%%% NEED TO REPLOT %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                plot(app.(['peak',num2str(ii)]), app.waveForms{1,ii}, 'Color', app.eleColor.(esingE));
                xlim(app.(['peak',num2str(ii)]),[0 app.totLen]);
                ylim(app.(['peak',num2str(ii)]),app.yLIMs);
                app.(['peak',num2str(ii)]).XTick = [];
                app.(['peak',num2str(ii)]).YTick = [];
                titleStr = num2str(size(tempAllWAVES,2));
                title(app.(['peak',num2str(ii)]),titleStr,'Color',app.eleColor.(esingE));
                
                %%%% ADD YES and NO
                updateQC(app)

            end
           
        end
        
        function export_data(app)
            
            data2save = app.SpikeInfo;
            
            % Create new Save folder if necessary
            
            cd(app.SaveLoc)
            
            % Include in SaveName set and surgery number
            
            if length(app.caseNum.Text) == 10
                cNParts = strsplit(app.caseNum.Text,'_');
                caseN = strjoin(cNParts,'');
                caseNUM = [caseN , '_0'];
            elseif length(app.caseNum.Text) > 10
                cNParts = strsplit(app.caseNum.Text,'_');
                caseN = strjoin(cNParts(1:3),'');
                caseNUM = [caseN,'_',cNParts{4}];
            end
            
            if app.TOTel == 1
                data2save.DepthIND = cellfun(@(x) [x ; [0 ; 0]], data2save.DepthIND,'UniformOutput', false);
            elseif app.TOTel == 2
                data2save.DepthIND = cellfun(@(x) [x ; 0], data2save.DepthIND,'UniformOutput', false);
            end

            
            fdn = cell(300,1);
            edn = cell(300,1);
            cCount = 1;
            for fi = 1:length(data2save.DepthID)
                for ei = 1:3
                    if data2save.DepthIND{fi}(ei)
                        fdn{cCount} = data2save.DepthID{fi};
                        if strcmp(handles.recFlag,'no')
                            edn{cCount} = ['CSPK_0',num2str(ei)];
                        else
                            edn{cCount} = ['CElectrode',num2str(ei)];
                        end
                        cCount = cCount + 1;
                    end
                end
            end
            
            fdn = fdn(cellfun(@(x) ~isempty(x), fdn));
            edn = edn(cellfun(@(x) ~isempty(x), edn));
            
            fedTable1 = cell2table([fdn,edn],'VariableNames',{'Depth','ElNum'});
            
            tempCol = false(height(fedTable1),1);
            tTable = table(tempCol,'VariableNames',{'Analyzed'});
            
            fedTable = [fedTable1 , tTable];
            
            data2save.fedTable = fedTable;
            
            saveName = strcat(caseNUM,'-setNum_',app.setNum,'_TOspk.mat');
            
            save(saveName,'-struct','data2save');
        end
        
        function new_file_process(app)
            
            if app.activeFile.Num == app.FileTotal
                app.messageTxt.Text = 'Files exported!! Select either ''L'' for a new folder or ''X'' to Exit...';
            else
            
                if strcmp(app.MOVE,'forward')
                    app.activeFile.Num = app.activeFile.Num + 1;
                else
                    if app.activeFile.Num == 1
                        app.activeFile.Num = app.activeFile.Num;
                    else
                        app.activeFile.Num = app.activeFile.Num - 1;
                    end
                end

                app.activeFile.Name = app.fNames{app.activeFile.Num};
            
                load(app.activeFile.Name);
            
                % Determine if CSPK or CElectrode
            
                if strcmp(app.recFlag,'mg')
                    for eleI = 1:app.TOTel
                        app.activeE.(['E',num2str(eleI)]) = eval(['CElectrode',num2str(eleI)]);
                    end
                else
                    for eleI = 1:app.TOTel
                        app.activeE.(['E',num2str(eleI)]) = eval(['CSPK_0',num2str(eleI)]);
                    end
                end
            
                app.activeE.Temp = app.activeE.E1;
            
                app.activeFR = round(mer.sampFreqHz);
                % Calculate Segments
            
                app.activeLength.sPoints = length(app.activeE.Temp);
                app.activeLength.tPoints = round(length(app.activeE.Temp)/app.activeFR); % in seconds
            
                % Load axis image
                axes(app.ele1win) % ELEWIN%%%%%%%%%%%%%%%%%
            
                % Set default threshold
                app.thrshSD.Value = 4;
            
                % Reset Zoom button
                app.zoomOUT.Value = 0;
            
                %%% UPDATE
                % Plot first segment
                app.activeLength.numSegs = floor(length(app.activeE.Temp)/(app.activeFR));
                app.activeLength.allSampPoints = 1:app.activeLength.numSegs*round(app.activeFR);
            
                app.activeLength.segSamps =...
                    reshape(app.activeLength.allSampPoints,...
                    [round(app.activeFR), app.activeLength.numSegs])';

                colorS = [1 , 0 , 0;
                    0 , 0 , 1;
                    0 , 0.49 , 0];
                for eleI = 1:app.TOTel
                    
                    eID = ['E',num2str(eleI)];
                    
                    app.eleColor.(['E',num2str(eleI)]) = colorS(eleI,:);
                    app.threshL.(['E',num2str(eleI)]) = round(mean(double(app.activeE.(eID)))+(std(double(app.activeE.(eID))) * app.thrshSD.Value));
                    app.rem.(['E',num2str(eleI)]) = round(mean(double(app.activeE.(eID)))+(std(double(app.activeE.(eID))) * 8));
                    
                end

                % Plot function
                for eleI = 1:app.TOTel

                    updatePlot(app , eleI)
                    
                end

                app.NUMsegs.Text = '1';
                app.curSeg = app.NUMsegs.Text;

            end
        end
        
        
        function updatePlot(app , ENUM)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            esing = num2str(ENUM);
            esingE = ['E',esing];
            
            yDatAll = app.curEledat(ENUM,:);
            segIND = app.segIDs.(app.activeFile(1:end-4)).segSE(str2double(app.NUMsegs.Text),:);
            y2plot = yDatAll(segIND(1):segIND(2));

            %%% Calculate Spike and Noise thresholds here

            colorS = [1 , 0 , 0;
                0 , 0 , 1;
                0 , 0.49 , 0];

            % remove nans
            thrYdat = yDatAll(~isnan(yDatAll));
            
            tmpThreshMp = std(double(thrYdat)) * app.thrshSD.Value;
            tmpThreshNoise = std(double(thrYdat)) * 8;
            tmpMean = round(mean(double(thrYdat)));
            yLIMstd = std(double(thrYdat)) * (app.thrshSD.Value + 4);


            app.eleColor.(esingE) = colorS(ENUM,:);
            app.threshL.(esingE) = tmpMean +...
                tmpThreshMp;
            app.rem.(esingE) = tmpMean +...
                tmpThreshNoise;
            app.YLIMi = tmpMean + yLIMstd;
            
            plot(app.(['ele',num2str(ENUM),'win']) , y2plot,...
                'Color',app.eleColor.(esingE))
            
            yline(app.(['ele',num2str(ENUM),'win']),app.threshL.(esingE),'Color','k','LineStyle','--','LineWidth',1.5)
            yline(app.(['ele',num2str(ENUM),'win']),-(app.threshL.(esingE)),'Color','k','LineStyle','--','LineWidth',1.5)
            
            % set ylim
 
            ylim(app.(['ele',num2str(ENUM),'win']),[-(app.YLIMi) app.YLIMi]);
            xlim(app.(['ele',num2str(ENUM),'win']),[1 numel(y2plot)])
            
            % Plot Waveforms above baseline
            
            spkData = double(y2plot);
            
            app.minDist = round(app.newFS/1000) * 1.9;
            app.sampLen = round(app.newFS/1000);
            
            [app.locs_Rwave{1,ENUM} ,  ~] = peakseek(spkData , app.minDist , app.threshL.(esingE));
            
            negSpkDat = spkData;
            
            negSpkDat(negSpkDat > 0) = 0;
            
            [app.locs_NGwave{1,ENUM} ,  ~] = peakseek(abs(negSpkDat) , app.minDist , app.threshL.(esingE));
            [app.peakP2rem{1,ENUM} ,  ~] = peakseek(spkData , app.minDist , app.rem.(esingE));
            [app.peakN2rem{1,ENUM} ,  ~] = peakseek(abs(negSpkDat) , app.minDist , app.rem.(esingE));
            
            app.ind2remPPks{1,ENUM} = ~ismember(app.locs_Rwave{1,ENUM},app.peakP2rem{1,ENUM});
            app.locs_Rwave{1,ENUM} = app.locs_Rwave{1,ENUM}(app.ind2remPPks{1,ENUM});
            
            app.ind2remNPks{1,ENUM} = ~ismember(app.locs_NGwave{1,ENUM},app.peakN2rem{1,ENUM});
            app.locs_NGwave{1,ENUM} = app.locs_NGwave{1,ENUM}(app.ind2remNPks{1,ENUM});
            
            app.totLen = length(1 - (app.sampLen/2):1 + (round(app.sampLen*0.8)-1));
            
            app.PwaveForms{1,ENUM} = zeros(app.totLen,length(app.locs_Rwave{1,ENUM}));
            for ai = 1:length(app.locs_Rwave{1,ENUM})
                locP = app.locs_Rwave{1,ENUM}(ai);
                sampPeriod = round(locP - (app.sampLen/2):locP + (round(app.sampLen*0.8)-1));
                if any(sampPeriod > length(spkData))
                    app.PwaveForms{1,ENUM}(:,ai) = [];
                    break
                else
                    app.PwaveForms{1,ENUM}(:,ai) = spkData(sampPeriod);
                end
            end
    
            % Get rid of points to close to edge NEW 6/15/2016
            vals2short = app.locs_NGwave{1,ENUM} < app.newFS*0.05;
            app.locs_NGwave{1,ENUM} = app.locs_NGwave{1,ENUM}(~vals2short);
            
            app.NwaveForms{1,ENUM} = zeros(app.totLen,length(app.locs_NGwave{1,ENUM}));
            for ai = 1:length(app.locs_NGwave{1,ENUM})    
                locP = app.locs_NGwave{1,ENUM}(ai);
                sampPeriod = round(locP - (app.sampLen/2):locP + (round(app.sampLen*0.8)-1));   
                if any(sampPeriod > length(spkData))
                    app.NwaveForms{1,ENUM}(:,ai) = [];
                    break
                else
                    app.NwaveForms{1,ENUM}(:,ai) = (spkData(sampPeriod))*-1;
                end
            end
            
            app.waveForms{1,ENUM} = [app.NwaveForms{1,ENUM} , app.PwaveForms{1,ENUM}];
            
            % PLOT data
            minPeakval = min(min(app.waveForms{1,ENUM}));
            minYval = minPeakval + (minPeakval * 0.1);
            maxPeakval = max(max(app.waveForms{1,ENUM}));
            maxYval = maxPeakval + (maxPeakval * 0.1);
            
            % Maybe interpolate for visualization ???????
            
            plot(app.(['peak',num2str(ENUM)]), app.waveForms{1,ENUM}, 'Color', app.eleColor.(esingE));
            xlim(app.(['peak',num2str(ENUM)]),[1 app.totLen]);
            ylim(app.(['peak',num2str(ENUM)]),[minYval maxYval]);
            app.(['peak',num2str(ENUM)]).XTick = [];
            app.(['peak',num2str(ENUM)]).YTick = [];
            titleStr = num2str(size(app.waveForms{1,ENUM},2));
            title(app.(['peak',num2str(ENUM)]),titleStr,'Color',app.eleColor.(esingE));
            
            
        end
        
        function updateSEG(app)

            % Plot first segment
            plotSEG = str2double(app.curSeg);
            timeAXact = app.activeLength.segSamps(plotSEG,:);
            timeAXreal = timeAXact/app.activeFR;
            
            for i = 1:app.TOTel
                activeEle = app.activeE.(strcat('E',num2str(i)));
                eleCol = app.eleColor.(strcat('E',num2str(i)));
                
                plot(app.(['ele',num2str(i),'win']) ,timeAXreal,...
                    activeEle(app.activeLength.segSamps(plotSEG,:)),...
                    'Color',eleCol)
                
                xlim(app.(['ele',num2str(i),'win']) ,[timeAXreal(1) timeAXreal(end)])
                ylim(app.(['ele',num2str(i),'win']) ,app.yLIMs);
                xticks(app.(['ele',num2str(i),'win']) , [])
                yticks(app.(['ele',num2str(i),'win']) , [])
                
                % Plot threshold line
                yTopLine = [timeAXreal(1) timeAXreal(end)];
                yBotLine = [-(app.threshL.(['E',num2str(i)])) (app.threshL.(['E',num2str(i)]));...
                    -(app.threshL.(['E',num2str(i)])) (app.threshL.(['E',num2str(i)]))];
                
                line(app.(['ele',num2str(i),'win']) , yTopLine, yBotLine,'Color','k','LineStyle','--','LineWidth',1.5)
                ylim(app.(['ele',num2str(i),'win']) , app.yLIMs);
            end

        end
        
        function updateThresh(app)
            
            % Recompute Threshold
            for eleI = 1:app.TOTel
                elN = ['E',num2str(eleI)];
                app.threshL.(elN) = round(mean(double(app.activeE.(elN)))+(std(double(app.activeE.(elN))) * app.thrshSD.Value));
            end
            
            compute_newWaves(app);
            app.zoomOUT.Value = 0;
            updateZoom(app);

        end
        
        function updateZoom(app)
            if app.zState
                for i = 1:app.TOTel
                    activeEle = app.activeE.(strcat('E',num2str(i)));
                    eleCol = app.eleColor.(strcat('E',num2str(i)));
                    activeEDn = downsample(activeEle,3);
                    plot(app.(['ele',num2str(i),'win']),activeEDn,...
                        'Color',eleCol)
                    xlim(app.(['ele',num2str(i),'win']),[1 length(activeEDn)])
                    
                    ylim(app.(['ele',num2str(i),'win']),app.yLIMs);
                    app.(strcat('ele',num2str(i),'win')).XTick = [];
                    app.(strcat('ele',num2str(i),'win')).YTick = [];
                    
                    yTopLine = [1 length(activeEDn)];
                    yBotLine = [-(app.threshL.(['E',num2str(i)])) (app.threshL.(['E',num2str(i)]));...
                        -(app.threshL.(['E',num2str(i)])) (app.threshL.(['E',num2str(i)]))];
                    
                    line(app.(['ele',num2str(i),'win']) , yTopLine, yBotLine,'Color','k','LineStyle','--','LineWidth',1.5)
                    ylim(app.(['ele',num2str(i),'win']) , app.yLIMs);
                end
                % Turn off Segment buttons
                app.seg_back.Enable = 'off';
                app.seg_for.Enable = 'off';
            else
                updateSEG(app)
                % Turn on Segment buttons
                app.seg_back.Enable = 'on';
                app.seg_for.Enable = 'on';
            end
        end
        
        function updateQC(app)
            
            topFldns = fieldnames(app.SpikeInfo);
            
            if ~ismember('DepthID',topFldns)
                for ei = 1:app.TOTel
                    hold(app.(['peak',num2str(ei)]), 'on')
                    xlabel(app.(['peak',num2str(ei)]), 'NA')
                    hold(app.(['peak',num2str(ei)]), 'off')
                end
                return
            end
            
            idFldns = app.SpikeInfo.DepthID;
            
            if ~ismember(app.activeFile, idFldns)
                for ei = 1:app.TOTel
                    hold(app.(['peak',num2str(ei)]), 'on')
                    xlabel(app.(['peak',num2str(ei)]), 'NA')
                    hold(app.(['peak',num2str(ei)]), 'off')
                end
                return
            end
            
            
            for ei = 1:app.TOTel
                
                hold(app.(['peak',num2str(ei)]), 'on')
                
                if app.SpikeInfo.DepthIND{ismember(idFldns,app.activeFile)}(ei)
                    xlabi = 'YES';
                else
                    xlabi = 'NO';
                end
                xlabel(app.(['peak',num2str(ei)]), xlabi)
                hold(app.(['peak',num2str(ei)]), 'off')
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.messageTxt.Text = 'Press the ''L'' Button to initiate the program';
            app.curTHRESH = app.thrshSD.Value;
            app.curSeg = app.NUMsegs.Text;
        end

        % Button pushed function: last_file
        function last_file_Callback(app, event)
            app.MOVE = 'backward';

            new_file_process(app);
            updateQC(app)
            % Depth Num
            app.depthVal.Text = app.activeFile.Name;
            app.curFile.Text = num2str(app.activeFile.Num);
            
            % Reset Buttons
            for si = 1:3
                app.(['E',num2str(si),'Switch']).Value = "NO";
            end

        end

        % Clicked callback: loadFolder
        function loadFolder_ClickedCallback(app, event)

            rawDataLoc = uigetdir('','LOAD MAIN DIRECTORY');
            
            % Load location with Spike Files to Cluster - RAW_Ephys_Files
            cd(rawDataLoc)
            checkFdir = 1;
            mrdir = dir;
            mrDirFolds1 = {mrdir.name};
            mrDirFoldsC = mrDirFolds1(~ismember(mrDirFolds1,{'.','..','.MATLABDriveTag'}));
            
            while checkFdir
                if sum(contains({'ClusteredSpikeTimes',...
                        'RAW_Ephys_Files',...
                        'SpikeDates2Cluster'},mrDirFoldsC)) == 3
                    checkFdir = 0;
                else
                    rawDataLoc = uigetdir('','LOAD MAIN CASE DIRECTORY');
                    cd(rawDataLoc)
                    checkFdir = 1;
                    mrdir = dir;
                    mrDirFolds1 = {mrdir.name};
                    mrDirFoldsC = mrDirFolds1(~ismember(mrDirFolds1,{'.','..'}));
                end
            end
            
            app.FolderMain = rawDataLoc;
            app.SaveLoc = [rawDataLoc , '\SpikeDates2Cluster\'];
            rawFoldALL = [rawDataLoc , '\RAW_Ephys_Files'];
            cd(rawFoldALL)
            
            % CONSIDER ADDING LIST BOX
            
            rawFoldStart = uigetdir(rawFoldALL,'LOAD MAIN DIRECTORY');
            cd(rawFoldStart);
            
            % Test folder with data
            % cd('Y:\PreProcessEphysData\07_20_2015')
            
            % Get mat file list
            mDir = dir('*.mat');
            mFnames = {mDir.name};
            
            % Reorder with Abv and Below separate
            mFnamesSp = cellfun(@(x) strsplit(x,'_'), mFnames, 'UniformOutput',false);
            abF = cellfun(@(x) x{1}, mFnamesSp, 'UniformOutput',false);
            
            aInd = cellfun(@(x) strcmp(x,'AbvTrgt'), abF);
            
            depthNumsA = str2double(cellfun(@(x) x{2}, mFnamesSp(aInd), 'UniformOutput',false));
            depthNumsB = str2double(cellfun(@(x) x{2}, mFnamesSp(~aInd), 'UniformOutput',false));
            
            [~ , newOrderA] = sort(depthNumsA);
            [~ , newOrderB] = sort(depthNumsB);
            
            mFnamesA = mFnames(aInd);
            mFnamesB = mFnames(~aInd);
            
            mFnamesF = [mFnamesA(newOrderA)   mFnamesB(newOrderB)];
            
            % Save mat file list as cell array in handles
            app.fNames = mFnamesF;
            app.FileTotal = numel(mFnamesF);
            app.activeFileNum = 1;
            app.activeFile = app.fNames{app.activeFileNum};
            
            % PROCESS NEW COMPRESSION strategy


            % Get spike dimensions of all files
            spkDim = zeros(length(mFnamesF),1);
            for mi = 1:length(mFnamesF)

                tmpMfile = matfile(mFnamesF{mi});

                tmpWholist = whos(tmpMfile);
                
                % Determine if CSPK or CElectrode
                if any(contains({tmpWholist.name},'CElectrode'))
                    app.recFlag = 'mg';
                else
                    app.recFlag = 'no';
                end
                
                tmpWhoNames = {tmpWholist.name};
                switch app.recFlag
                    case 'mg'
                        justSpks = tmpWholist(ismember(tmpWhoNames,'CElectrode_01')).size(2);
                    case 'no'
                        justSpks = tmpWholist(ismember(tmpWhoNames,'CSPK_01')).size(2);
                end
                %                 dn4 = ceil(justSpks/4);

                spkDim(mi) = justSpks;

            end

            % File number * electode number
            switch app.recFlag
                case 'mg'
                    cspkS = tmpWhoNames(contains(tmpWhoNames,'CElectrode'));
                case 'no'
                    cspkS = tmpWhoNames(contains(tmpWhoNames,'CSPK'));
            end
            
            load(mFnamesF{1},'mer')
            
            uniqueVals = unique(cellfun(@(x) str2double(x(7)), cspkS, 'UniformOutput',true));
            TotalVals = numel(uniqueVals);
            app.newFS = round(mer.sampFreqHz/4);

            % Combine and downsample
            % All data matrix
            app.allDAT = nan(TotalVals*length(spkDim) , max(spkDim), 'single');
            % Case ID
            app.caseID = cell(TotalVals*length(spkDim) , 1);
            % Electrode ID
            app.elecID = zeros(TotalVals*length(spkDim) , 1);
            % Segment indices
            app.segIDs = struct;
            rowC = 1;
            for ai = 1:length(spkDim)
                load(mFnamesF{ai})
                for ei = 1:TotalVals

                    switch app.recFlag
                        case 'mg'
                            tmpEf = eval(['CElectrode_0',num2str(uniqueVals(ei))]);
                        case 'no'
                            tmpEf = eval(['CSPK_0',num2str(uniqueVals(ei))]);
                    end
                    tmpEfdn = downsample(tmpEf,4);

                    app.allDAT(rowC , 1:length(tmpEfdn)) = int16(tmpEfdn);

                    app.caseID{rowC} = mFnamesF{ai};
                    app.elecID(rowC) = uniqueVals(ei);

                    rowC = rowC + 1;
                end

                % Figure out Segs
                minS = floor(length(tmpEfdn)/app.newFS);
                segBlkbase = (0:minS)*app.newFS;
                segStarts = (segBlkbase) + 1;
                segEnds = [segBlkbase(2:end),length(tmpEfdn)];

                tmpFn = strsplit(mFnamesF{ai},'.');
                tmpFnS = tmpFn{1};

                app.segIDs.(tmpFnS).segSE(:,1) = segStarts;
                app.segIDs.(tmpFnS).segSE(:,2) = segEnds;

            end

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
%             colorS = [1 , 0 , 0;
%                       0 , 0 , 1;
%                       0 , 0.49 , 0];
            % 1. Get current file indices - loop through electrodes
            app.activeFile = mFnamesF{1};
            tmpActIND = ismember(app.caseID,app.activeFile);
            tmpEleNum = app.elecID(tmpActIND);
            % 2. Compute necessary stats from raw data table
            tmpRawDat = app.allDAT(tmpActIND,:);
%             
%             for eleI = 1:numel(tmpEleNum)
%                 
%                 elecNUMi = tmpEleNum(eleI);
%                 
%                 tmpThreshMp = std(double(tmpRawDat(eleI,:))) * app.thrshSD.Value;
%                 tmpThreshNoise = std(double(tmpRawDat(eleI,:))) * 8;
%                 tmpMean = round(mean(double(tmpRawDat(eleI,:))));
%                 
%                 app.eleColor.(['E',num2str(elecNUMi)]) = colorS(eleI,:);
%                 app.threshL.(['E',num2str(elecNUMi)]) = tmpMean +...
%                     tmpThreshMp; 
%                 app.rem.(['E',num2str(elecNUMi)]) = tmpMean +...
%                     tmpThreshNoise;
%                 
%             end
            
            app.curEledat = tmpRawDat;

            app.locs_Rwave = cell(1,3);
            app.locs_NGwave = cell(1,3);
            app.peakP2rem = cell(1,3);
            app.peakN2rem = cell(1,3);
            
            app.ind2remPPks = cell(1,3);
            app.locs_Rwave = cell(1,3);
            
            app.ind2remNPks = cell(1,3);
            app.locs_NGwave = cell(1,3);
            app.PwaveForms = cell(1,3);
            
            app.waveForms = cell(1,3);
            app.NwaveForms = cell(1,3);
            
            % Plot function

            for eleI = 1:numel(tmpEleNum)
                updatePlot(app , eleI)
            end
            
            app.NUMsegs.Text = '1';
            allFileParts = strsplit(rawFoldStart,'\');
            
            if strcmp(allFileParts{length(allFileParts)}(1:3),'Set')
                % Determine set number
                app.setNum = allFileParts{length(allFileParts)}(4);
                surgNumStr = allFileParts{length(allFileParts)-1};
                surgNumParts = strsplit(surgNumStr,'_');
                if length(surgNumParts) > 3
                    % Determine surgery number
                    app.surgNum = surgNumParts{length(surgNumParts)};
                else
                    app.surgNum = '1';
                end
                app.caseNum.Text = allFileParts{length(allFileParts)-1};
            else
                app.setNum = '0';
                surgNumStr = allFileParts{length(allFileParts)};
                surgNumParts = strsplit(surgNumStr,'_');
                if length(surgNumParts) > 3
                    % Determine surgery number
                    app.surgNum = surgNumParts{length(surgNumParts)};
                else
                    app.surgNum = '1';
                end
                app.caseNum.Text = allFileParts{length(allFileParts)};
            end
            
            app.SpikeInfo.CaseNum = app.caseNum.Text;
            app.SpikeInfo.DepthID = repmat({''},size(transpose(app.fNames)));

            app.depthVal.Text = app.activeFile;

            % Find absent electrodes
            if numel(tmpEleNum) == 1
                app.E2Switch.Value = 1;
                app.E3Switch.Value = 1;         
            elseif numel(tmpEleNum) == 2
                app.E3Switch.Value = 1;     
            end

            app.seg_back.Enable = 'on';
            app.seg_for.Enable = 'on';
            app.next_file.Enable = 'on';
            app.last_file.Enable = 'on';
            
            app.totFile.Text = num2str(app.FileTotal);
            app.curFile.Text = num2str(1);
            
            % Set up Export data structure
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%FIXXXXXXXXXXX
            app.SpikeInfo.CaseNum = app.caseNum.Text;
            app.QCpanel.Enable = 'on';
            app.zoomOUT.Enable = 'on';
            app.seg_back.Enable = 'on';
            app.seg_for.Enable = 'on';
            
            updateQC(app)
            
            app.messageTxt.Text = 'Folder Successfully loaded!';

        end

        % Button pushed function: next_file
        function next_file_Callback(app, event)

            app.MOVE = 'forward';
            new_file_process(app);
            updateQC(app)
            % Depth Num
            app.depthVal.Text = app.activeFile.Name;
            app.curFile.Text = num2str(app.activeFile.Num);

            % Reset Buttons
            for si = 1:3
                app.(['E',num2str(si),'Switch']).Value = "NO";
            end

            set(app.seg_back,'Enable','on');
            set(app.seg_for,'Enable','on');

        end

        % Button pushed function: seg_back
        function seg_back_Callback(app, event)

            app.curSeg = app.NUMsegs.Text;
            if str2double(app.curSeg) == 1
                app.messageTxt.Text = 'At the LOWEST segment';
                return
            else
                % Update Segment Handle
                app.curSeg = num2str(str2double(app.curSeg) - 1);
                updateSEG(app)
                app.NUMsegs.Text = app.curSeg;
                app.messageTxt.Text = 'Advanced Segment Window BACKWARD';
            end

        end

        % Button pushed function: seg_for
        function seg_for_Callback(app, event)

            app.curSeg = app.NUMsegs.Text;
            if str2double(app.curSeg) == app.activeLength.numSegs
                app.messageTxt.Text = 'At the HIGHEST segment';
                return
            else
                app.curSeg = num2str(str2double(app.curSeg) + 1);
                updateSEG(app)
                app.NUMsegs.Text = app.curSeg;
                app.messageTxt.Text = 'Advanced Segment Window FORWARD';
            end

        end

        % Value changed function: zoomOUT
        function zoomOUT_Callback(app, event)

            app.zState = app.zoomOUT.Value;
            updateZoom(app)
            
        end

        % Clicked callback: closeProgram
        function closeProgramClicked(app, event)
            figure1CloseRequest(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end

        % Value changed function: thrshSD
        function thrshSDValueChanged(app, event)
            stepThresh = app.thrshSD.Value;
            app.curTHRESH = stepThresh;
            if app.curTHRESH <= 2
                app.thrshSD.Value = 3;
                return
            elseif app.curTHRESH >= 7
                app.thrshSD.Value = 6;
                return
            else
                updateThresh(app)
            end

        end

        % Button pushed function: appSel
        function appSelButtonPushed(app, event)

            app.SpikeInfo.DepthID{app.activeFile.Num,1} = app.activeFile.Name;
            % Apply button selection 
            for swi = 1:app.TOTel
                
                
                if strcmp(app.(['E',num2str(swi),'Switch']).Value,'YES')
                    app.SpikeInfo.DepthIND{app.activeFile.Num,1}(swi,1) = 1;
                else
                    app.SpikeInfo.DepthIND{app.activeFile.Num,1}(swi,1) = 0;
                end
                
            end
            % Advance forward if not at the end
            app.next_file_Callback(app)

        end

        % Clicked callback: exporT
        function exporTClicked(app, event)
            export_data(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SpikeDetectViewer_MultiTrode_v06_beta';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.loadFolder = uipushtool(app.uitoolbar1);
            app.loadFolder.Tag = 'loadFolder';
            app.loadFolder.ClickedCallback = createCallbackFcn(app, @loadFolder_ClickedCallback, true);
            app.loadFolder.Icon = 'loadFolder_image.png';
            app.closeProgram = uipushtool(app.uitoolbar1);
            app.closeProgram.Tag = 'closeProgram';
            app.closeProgram.ClickedCallback = createCallbackFcn(app, @closeProgramClicked, true);
            app.closeProgram.Icon = 'closeProgram_image.png';
            app.exporT = uipushtool(app.uitoolbar1);
            app.exporT.ClickedCallback = createCallbackFcn(app, @exporTClicked, true);
            app.exporT.Icon = 'exportS.png';
            app.RawTracesPanel = uipanel(app.figure1);
            app.RawTracesPanel.Title = 'Raw Traces';
            app.GridLayout = uigridlayout(app.RawTracesPanel);
            app.ele1win = uiaxes(app.GridLayout);
            zlabel(app.ele1win, 'Z')
            app.ele1win.XTick = [];
            app.ele1win.YTick = [];
            app.ele2win = uiaxes(app.GridLayout);
            zlabel(app.ele2win, 'Z')
            app.ele2win.XTick = [];
            app.ele2win.YTick = [];
            app.ele3win = uiaxes(app.GridLayout);
            zlabel(app.ele3win, 'Z')
            app.ele3win.XTick = [];
            app.ele3win.YTick = [];
            app.peak1 = uiaxes(app.GridLayout);
            app.peak1.XTick = [];
            app.peak1.YTick = [];
            app.peak2 = uiaxes(app.GridLayout);
            app.peak2.XTick = [];
            app.peak2.YTick = [];
            app.peak3 = uiaxes(app.GridLayout);
            app.peak3.XTick = [];
            app.peak3.YTick = [];
            app.last_file = uibutton(app.figure1, 'push');
            app.last_file.ButtonPushedFcn = createCallbackFcn(app, @last_file_Callback, true);
            app.last_file.Tag = 'last_file';
            app.last_file.Enable = 'off';
            app.last_file.Text = '<- File';
            app.zoomOUT = uibutton(app.figure1, 'state');
            app.zoomOUT.ValueChangedFcn = createCallbackFcn(app, @zoomOUT_Callback, true);
            app.zoomOUT.Tag = 'zoomOUT';
            app.zoomOUT.Enable = 'off';
            app.zoomOUT.Text = 'Zoom OUT';
            app.messageTxt = uilabel(app.figure1);
            app.messageTxt.Tag = 'messageTxt';
            app.messageTxt.Text = 'Program Status';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'out of';
            app.totFile = uilabel(app.figure1);
            app.totFile.Tag = 'totFile';
            app.totFile.Text = '0';
            app.curFile = uilabel(app.figure1);
            app.curFile.Tag = 'curFile';
            app.curFile.Text = '0';
            app.depthVal = uilabel(app.figure1);
            app.depthVal.Tag = 'depthVal';
            app.depthVal.Text = 'AbvTrgt_00_0000.mat';
            app.caseNum = uilabel(app.figure1);
            app.caseNum.Tag = 'caseNum';
            app.caseNum.Text = '01/01/1901';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'DEPTH #';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'CASE #';
            app.next_file = uibutton(app.figure1, 'push');
            app.next_file.ButtonPushedFcn = createCallbackFcn(app, @next_file_Callback, true);
            app.next_file.Tag = 'next_file';
            app.next_file.Enable = 'off';
            app.next_file.Text = 'File ->';
            app.NUMsegs = uilabel(app.figure1);
            app.NUMsegs.Tag = 'NUMsegs';
            app.NUMsegs.Text = '1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Seg. Num';
            app.seg_for = uibutton(app.figure1, 'push');
            app.seg_for.ButtonPushedFcn = createCallbackFcn(app, @seg_for_Callback, true);
            app.seg_for.Tag = 'seg_for';
            app.seg_for.Enable = 'off';
            app.seg_for.Text = 'Forwd';
            app.seg_back = uibutton(app.figure1, 'push');
            app.seg_back.ButtonPushedFcn = createCallbackFcn(app, @seg_back_Callback, true);
            app.seg_back.Tag = 'seg_back';
            app.seg_back.Enable = 'off';
            app.seg_back.Text = 'Back';
            app.QCpanel = uipanel(app.figure1);
            app.QCpanel.Enable = 'off';
            app.QCpanel.Title = 'Quality Control Check';
            app.E1SwitchLabel = uilabel(app.QCpanel);
            app.E1SwitchLabel.Text = 'E1';
            app.E1Switch = uiswitch(app.QCpanel, 'toggle');
            app.E1Switch.Items = {'NO', 'YES'};
            app.E1Switch.Value = 'NO';
            app.E2SwitchLabel = uilabel(app.QCpanel);
            app.E2SwitchLabel.Text = 'E2';
            app.E2Switch = uiswitch(app.QCpanel, 'toggle');
            app.E2Switch.Items = {'NO', 'YES'};
            app.E2Switch.Value = 'NO';
            app.E3SwitchLabel = uilabel(app.QCpanel);
            app.E3SwitchLabel.Text = 'E3';
            app.E3Switch = uiswitch(app.QCpanel, 'toggle');
            app.E3Switch.Items = {'NO', 'YES'};
            app.E3Switch.Value = 'NO';
            app.SDThreshLabel = uilabel(app.figure1);
            app.SDThreshLabel.Text = 'SD Thresh.';
            app.thrshSD = uispinner(app.figure1);
            app.thrshSD.ValueChangedFcn = createCallbackFcn(app, @thrshSDValueChanged, true);
            app.thrshSD.Value = 4;
            app.appSel = uibutton(app.figure1, 'push');
            app.appSel.ButtonPushedFcn = createCallbackFcn(app, @appSelButtonPushed, true);
            app.appSel.Text = 'APPLY SELECTION';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpikeR_v07_beta_App5

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end