classdef VisualTraceNLX_v01 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        TabGroup       matlab.ui.container.TabGroup
        ChannelsTab    matlab.ui.container.Tab
        ChannelsPanel  matlab.ui.container.Panel
        ephysGrid      matlab.ui.container.GridLayout
        UIAxes_8       matlab.ui.control.UIAxes
        UIAxes_7       matlab.ui.control.UIAxes
        UIAxes_6       matlab.ui.control.UIAxes
        UIAxes_5       matlab.ui.control.UIAxes
        UIAxes_4       matlab.ui.control.UIAxes
        UIAxes_3       matlab.ui.control.UIAxes
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
        ParametersTab  matlab.ui.container.Tab
        MainMenuPanel  matlab.ui.container.Panel
        Load           matlab.ui.control.Button
        Label_2        matlab.ui.control.Label
        Label          matlab.ui.control.Label
        back           matlab.ui.control.Button
        forward        matlab.ui.control.Button
    end

    
    properties (Access = private)
        loadLOC % Description
        loadFILE
        nwbFILE
        microData
        tsData
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            [app.loadFILE , app.loadLOC] = uigetfile();
            cd(app.loadLOC)
            app.nwbFILE = nwbRead(app.loadFILE);
            % Read in raw microwire
            tmpNWBl = app.nwbFILE.acquisition.get('MicroWireSeries');
            app.tsData = tmpNWBl.timestamps.load();
            app.microData = transpose(tmpNWBl.data.load());

            % Length of Epoches = 10 seconds
            % Calculate the number of epochs
            numEpoches = width(app.microData)/(320000*10);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.forward = uibutton(app.MainMenuPanel, 'push');
            app.forward.Icon = 'backarrow.png';
            app.forward.Text = '';
            app.back = uibutton(app.MainMenuPanel, 'push');
            app.back.Icon = 'forwardarrow.png';
            app.back.Text = '';
            app.Label = uilabel(app.MainMenuPanel);
            app.Label.Text = '|';
            app.Label_2 = uilabel(app.MainMenuPanel);
            app.Label_2.Text = '|';
            app.Load = uibutton(app.MainMenuPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'L';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChannelsTab = uitab(app.TabGroup);
            app.ChannelsTab.Title = 'Channels';
            app.ChannelsPanel = uipanel(app.ChannelsTab);
            app.ChannelsPanel.Title = 'Channels';
            app.ephysGrid = uigridlayout(app.ChannelsPanel);
            app.UIAxes = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_7 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.ephysGrid);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualTraceNLX_v01

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end