classdef VisualTraceNLX_v02 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        ChannelViewTab          matlab.ui.container.Tab
        ChannelSelectPanel      matlab.ui.container.Panel
        C8Button                matlab.ui.control.StateButton
        C7Button                matlab.ui.control.StateButton
        C6Button                matlab.ui.control.StateButton
        C5Button                matlab.ui.control.StateButton
        C4Button                matlab.ui.control.StateButton
        C3Button                matlab.ui.control.StateButton
        C2Button                matlab.ui.control.StateButton
        C1Button                matlab.ui.control.StateButton
        ChanSel                 matlab.ui.control.Spinner
        CHANNELLabel            matlab.ui.control.Label
        ChannelDataPanel        matlab.ui.container.Panel
        ephysGrid               matlab.ui.container.GridLayout
        AbsThrs                 matlab.ui.control.UIAxes
        Filtered                matlab.ui.control.UIAxes
        RawView                 matlab.ui.control.UIAxes
        ParametersTab           matlab.ui.container.Tab
        MainMenuPanel           matlab.ui.container.Panel
        maxEp                   matlab.ui.control.NumericEditField
        MAXEpochEditFieldLabel  matlab.ui.control.Label
        epochC                  matlab.ui.control.Spinner
        EPOCHSpinnerLabel       matlab.ui.control.Label
        Load                    matlab.ui.control.Button
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        back                    matlab.ui.control.Button
        forward                 matlab.ui.control.Button
    end

    % TO DO
    % Populate thresholds
    % Create an UPDATEplot function


    properties (Access = private)
        loadLOC % Description
        loadFILE
        nwbFILE
        microData
        filtData
        absData
        tsData
        Fs
        procPROG
        meanALL
        sdALL
        numEpoches
        EpochInds
    end
    
    methods (Access = private)
        
        function [filterData , nFs] = mmfilterFun(app , mWireData , sFs)
            % Extract relevant NWB info
            elecTYPE = 'MIcro';

            app.procPROG.Message = ['Initiating Filtering for ', elecTYPE, ' data'];
            % Sampling frequency
            % Access the struct names in the NWB acquisition field
            mwireData = mWireData;
            % Extract channel data
            % Extract vector of data
            % Extract channel of interest
            ALLchannelData = mwireData;
            filterData = zeros(size(ALLchannelData),'int16');

            % loop through channel data
            app.meanALL = zeros(size(mwireData,2),3);
            app.sdALL = zeros(size(mwireData,2),3);
            for ci = 1:size(mwireData,2)

                app.procPROG.Message = ['Filtering file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,2))];
                tmpChannel = mwireData(:,ci);

                cgrDATAnew = tmpChannel;
                nFs = sFs;
                hpFreq = 600;
                lpFreq = 3000;

                app.meanALL(ci,1) = mean(cgrDATAnew);
                app.sdALL(ci,1) = std(cgrDATAnew);

                app.procPROG.Message = ['High pass file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,2))];
                [highPASS,~] = highpass(cgrDATAnew , hpFreq , nFs);

                app.procPROG.Message = ['Low pass file ', num2str(ci), ' out of ',...
                    num2str(size(mwireData,2))];

                [filterTmp,~] = lowpass(highPASS, lpFreq, nFs,...
                    'ImpulseResponse', 'iir', 'Steepness' ,0.8);

                filterData(:,ci) = filterTmp;
                app.meanALL(ci,2) = mean(filterTmp);
                app.sdALL(ci,2) = std(filterTmp);

                abFilt = abs(filterTmp);

                app.meanALL(ci,3) = mean(abFilt);
                app.sdALL(ci,3) = std(abFilt);

            end
            
        end
        
        function updatePLOT(app)

            % add channel ID - get from spinner
            chanID = app.ChanSel.Value;
            epochID = app.epochC.Value;
            plot(app.RawView,...
                app.microData(app.EpochInds(epochID,1):app.EpochInds(epochID,2),...
                chanID))

            plot(app.Filtered,...
                app.filtData(app.EpochInds(epochID,1):app.EpochInds(epochID,2),...
                chanID))

            plot(app.AbsThrs,...
                app.absData(app.EpochInds(epochID,1):app.EpochInds(epochID,2),...
                chanID))

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            [app.loadFILE , app.loadLOC] = uigetfile('*.nwb');
            cd(app.loadLOC)
            app.procPROG = uiprogressdlg(app.UIFigure,'Title','Loading NWB',...
                'Indeterminate','on');
            app.nwbFILE = nwbRead(app.loadFILE);
            close(app.procPROG)
            % Read in raw microwire
            tmpNWBl = app.nwbFILE.acquisition.get('MicroWireSeries');
            app.tsData = tmpNWBl.timestamps.load();
            app.microData = transpose(tmpNWBl.data.load());
            tmpFS = extractBetween(tmpNWBl.description,'=',':');
            app.Fs = str2double(replace(tmpFS{1},' ',''));

            % Length of Epoches = 5 seconds
            % Calculate the number of epochs
            app.numEpoches = floor(height(app.microData)/(32000*5));
            app.epochC.Limits = [1 app.numEpoches];
            app.maxEp.Value = app.numEpoches;
            startINDS = transpose(1:32000*5:(32000*app.numEpoches*5));
            stopINDS = [startINDS(2:end)-1 ; height(app.microData)];
            app.EpochInds = [startINDS , stopINDS];

            % Plot first 5 seconds of data for channel 1
            plot(app.RawView,app.microData(1:(32000*5),1))

            app.procPROG = uiprogressdlg(app.UIFigure,'Title','Creating Filtered signal',...
                'Indeterminate','on');

            [app.filtData , ~] = mmfilterFun(app , app.microData , app.Fs);
            ylim(app.RawView,[app.meanALL(1,1)-(app.sdALL(1,1)*5) app.meanALL(1,1)+(app.sdALL(1,1)*5)])
            ylim(app.Filtered,[app.meanALL(1,2)-(app.sdALL(1,2)*7) app.meanALL(1,2)+(app.sdALL(1,2)*7)])

            plot(app.Filtered,app.filtData(1:(32000*5),1))

            app.absData = abs(app.filtData);
            plot(app.AbsThrs,app.absData(1:(32000*5),1))
            ylim(app.AbsThrs,[app.meanALL(1,3)-(app.sdALL(1,3)*7) app.meanALL(1,3)+(app.sdALL(1,3)*7)])

            close(app.procPROG)


        end

        % Button pushed function: forward
        function forwardButtonPushed(app, event)
            % Update epoch spinner
            
            % Do something if hit end
            if app.epochC.Value == app.numEpoches
                app.epochC.Value = app.numEpoches;
                return
            else
                app.epochC.Value = app.epochC.Value + 1;
            end
            
            updatePLOT(app)
        end

        % Button pushed function: back
        function backButtonPushed(app, event)
            % Do something if hit end
            if app.epochC.Value == 1
                return
            else
                app.epochC.Value = app.epochC.Value - 1;
            end

            updatePLOT(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Main Menu';
            app.forward = uibutton(app.MainMenuPanel, 'push');
            app.forward.ButtonPushedFcn = createCallbackFcn(app, @forwardButtonPushed, true);
            app.forward.Icon = 'backarrow.png';
            app.forward.Text = '';
            app.back = uibutton(app.MainMenuPanel, 'push');
            app.back.ButtonPushedFcn = createCallbackFcn(app, @backButtonPushed, true);
            app.back.Icon = 'forwardarrow.png';
            app.back.Text = '';
            app.Label = uilabel(app.MainMenuPanel);
            app.Label.Text = '|';
            app.Label_2 = uilabel(app.MainMenuPanel);
            app.Label_2.Text = '|';
            app.Load = uibutton(app.MainMenuPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'L';
            app.EPOCHSpinnerLabel = uilabel(app.MainMenuPanel);
            app.EPOCHSpinnerLabel.Text = 'EPOCH';
            app.epochC = uispinner(app.MainMenuPanel);
            app.epochC.Limits = [1 300];
            app.epochC.Value = 1;
            app.MAXEpochEditFieldLabel = uilabel(app.MainMenuPanel);
            app.MAXEpochEditFieldLabel.Text = 'MAX Epoch';
            app.maxEp = uieditfield(app.MainMenuPanel, 'numeric');
            app.maxEp.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ChannelViewTab = uitab(app.TabGroup);
            app.ChannelViewTab.Title = 'ChannelView';
            app.ChannelDataPanel = uipanel(app.ChannelViewTab);
            app.ChannelDataPanel.Title = 'Channel Data';
            app.ephysGrid = uigridlayout(app.ChannelDataPanel);
            app.RawView = uiaxes(app.ephysGrid);
            zlabel(app.RawView, 'Z')
            subtitle(app.RawView, 'Raw Data')
            app.RawView.XTick = [];
            app.RawView.YTick = [];
            app.Filtered = uiaxes(app.ephysGrid);
            zlabel(app.Filtered, 'Z')
            subtitle(app.Filtered, 'Filtered Data')
            app.Filtered.XTick = [];
            app.Filtered.YTick = [];
            app.AbsThrs = uiaxes(app.ephysGrid);
            zlabel(app.AbsThrs, 'Z')
            subtitle(app.AbsThrs, 'Absolute - Threshold')
            app.AbsThrs.XTick = [];
            app.AbsThrs.YTick = [];
            app.ChannelSelectPanel = uipanel(app.ChannelViewTab);
            app.ChannelSelectPanel.Title = 'Channel Select';
            app.CHANNELLabel = uilabel(app.ChannelSelectPanel);
            app.CHANNELLabel.Text = 'CHANNEL';
            app.ChanSel = uispinner(app.ChannelSelectPanel);
            app.ChanSel.Limits = [1 8];
            app.ChanSel.Value = 1;
            app.C1Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C1Button.Text = 'C1';
            app.C2Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C2Button.Text = 'C2';
            app.C3Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C3Button.Text = 'C3';
            app.C4Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C4Button.Text = 'C4';
            app.C5Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C5Button.Text = 'C5';
            app.C6Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C6Button.Text = 'C6';
            app.C7Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C7Button.Text = 'C7';
            app.C8Button = uibutton(app.ChannelSelectPanel, 'state');
            app.C8Button.Text = 'C8';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualTraceNLX_v02

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end