classdef VisualTrace_v06 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uitoolbar1        matlab.ui.container.Toolbar
        loadFolder        matlab.ui.container.toolbar.PushTool
        closeProgram      matlab.ui.container.toolbar.PushTool
        exporT            matlab.ui.container.toolbar.PushTool
        accept            matlab.ui.control.StateButton
        thrshSD           matlab.ui.control.Spinner
        SDThreshLabel     matlab.ui.control.Label
        QCpanel           matlab.ui.container.Panel
        E2Switch          matlab.ui.control.ToggleSwitch
        E2SwitchLabel     matlab.ui.control.Label
        E3Switch          matlab.ui.control.ToggleSwitch
        E3SwitchLabel     matlab.ui.control.Label
        E1Switch          matlab.ui.control.ToggleSwitch
        E1SwitchLabel     matlab.ui.control.Label
        seg_back          matlab.ui.control.Button
        seg_for           matlab.ui.control.Button
        text2             matlab.ui.control.Label
        NUMsegs           matlab.ui.control.Label
        next_file         matlab.ui.control.Button
        zoomOUT           matlab.ui.control.StateButton
        FilestosortPanel  matlab.ui.container.Panel
        listFiles         matlab.ui.control.ListBox
        text7             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        caseNum           matlab.ui.control.Label
        depthVal          matlab.ui.control.Label
        curFile           matlab.ui.control.Label
        totFile           matlab.ui.control.Label
        text13            matlab.ui.control.Label
        messageTxt        matlab.ui.control.Label
        RawTracesPanel    matlab.ui.container.Panel
        GridLayout        matlab.ui.container.GridLayout
        peak3             matlab.ui.control.UIAxes
        peak2             matlab.ui.control.UIAxes
        peak1             matlab.ui.control.UIAxes
        ele3win           matlab.ui.control.UIAxes
        ele2win           matlab.ui.control.UIAxes
        ele1win           matlab.ui.control.UIAxes
    end

    %%%% TO DO
    % Fix Seg error
    % Fix output offset of 1
    % Fix when last file is encourntered


    properties (Access = private)
        mainFoldLoc % Description
        fil2spkFold
        visCdatFold
        vCfile
        AllElecs
        fNames
        Allcases
        AllDat
        AllSegs
        Allthresh
        Allwaves
        curThresh
        SaveLoc
        FileTotal
        activeFile
        activeFileNum
        activeE
        yLIMs
        eleColor
        threshL
        SpikeInfo
        totLen
        setNum
        surgNum
        MOVE
        curSeg
        curTHRESH
        curWaves
        zState
        allDAT
        curEledat
        YLIMi
        curALLSegs
        numELEc
        waVEForms
        loadUIprog
        fileUIprog
        surgINFO
        waVEForms2plot

    end

    methods (Access = private)

        function export_data(app)

            data2save = app.SpikeInfo;

            % Create new Save folder if necessary

            cd(app.fil2spkFold)

            if length(app.numELEc) == 1
                data2save.DepthIND = cellfun(@(x) [x ; [0 ; 0]], data2save.DepthIND,'UniformOutput', false);
            elseif length(app.numELEc) == 2
                data2save.DepthIND = cellfun(@(x) [x ; 0], data2save.DepthIND,'UniformOutput', false);
            end
            
            loopThdata = data2save.DepthID(cellfun(@(x) ~isempty(x), data2save.DepthID, 'UniformOutput', true));

            fdn = cell(300,1);
            edn = cell(300,1);
            cCount = 1;
            for fi = 1:length(loopThdata)
                for ei = 1:3
                    if data2save.DepthIND{fi}(ei)
                        fdn{cCount} = data2save.DepthID{fi};
                        if strcmp(app.surgINFO.machine,'no')
                            edn{cCount} = ['CSPK_0',num2str(ei)];
                        else
                            edn{cCount} = ['CElectrode',num2str(ei)];
                        end
                        cCount = cCount + 1;
                    end
                end
            end

            fdn = fdn(cellfun(@(x) ~isempty(x), fdn));
            edn = edn(cellfun(@(x) ~isempty(x), edn));

            fedTable1 = cell2table([fdn,edn],'VariableNames',{'Depth','ElNum'});

            tempCol = false(height(fedTable1),1);
            tTable = table(tempCol,'VariableNames',{'Analyzed'});

            fedTable = [fedTable1 , tTable];

            data2save.fedTable = fedTable;

            saveName = strcat(app.vCfile,'_TOspk.mat');

            save(saveName,'-struct','data2save');
            
            % Reopen File list
            app.listFiles.Enable = 'on';
            
        end

        function new_file_process(app)

            if app.activeFileNum == app.FileTotal
                
                app.messageTxt.Text = 'EXPORT FILES!!!!!';
                app.SpikeInfo.DepthID{app.activeFileNum} = app.activeFile;
                % Apply button selection
                for swi = 1:length(app.numELEc)

                    if strcmp(app.(['E',num2str(swi),'Switch']).Value,'YES')
                        app.SpikeInfo.DepthIND{app.activeFileNum}(swi,1) = 1;
                    else
                        app.SpikeInfo.DepthIND{app.activeFileNum}(swi,1) = 0;
                    end

                end
                % Turn off buttons
                app.QCpanel.Enable = 'off';
                app.accept.Enable = 'off';
                
                % Clear figures
                cla(app.ele1win)
                cla(app.ele2win)
                cla(app.ele3win)
                cla(app.peak1)
                cla(app.peak2)
                cla(app.peak3)
                
                % Clear titles
                title(app.peak1 , [])
                title(app.peak2 , [])
                title(app.peak3 , [])
                
                % Clear Text
                app.caseNum.Text = '0000000';
                app.depthVal.Text = '00000';
                
                % Export data
                export_data(app)
                
                % Let user know
                uialert(app.figure1 , 'EXPORT IS DONE!! Select next file!!',...
                    'EXPORT COMPLETE')
                
            else

                app.NUMsegs.Text = '1';
                % If button is pressed move on or return
                if app.accept.Value == 0
                    return
                else
                    app.SpikeInfo.DepthID{app.activeFileNum} = app.activeFile;
                    % Apply button selection
                    for swi = 1:length(app.numELEc)

                        if strcmp(app.(['E',num2str(swi),'Switch']).Value,'YES')
                            app.SpikeInfo.DepthIND{app.activeFileNum}(swi,1) = 1;
                        else
                            app.SpikeInfo.DepthIND{app.activeFileNum}(swi,1) = 0;
                        end

                    end
                    app.accept.Value = 0;
                end
  
                if strcmp(app.MOVE,'forward')
                    app.activeFileNum = app.activeFileNum + 1;
                else
                    if app.activeFileNum == 1
                        app.activeFileNum = app.activeFileNum;
                    else
                        app.activeFileNum = app.activeFileNum - 1;
                    end
                end

                app.activeFile = app.fNames{app.activeFileNum};

                % Set default threshold
                app.thrshSD.Value = 4;

                % Reset Zoom button
                app.zoomOUT.Value = 0;
                
                app.curEledat = app.AllDat(ismember(app.Allcases,app.activeFile),:);
                app.curALLSegs = app.AllSegs.(app.activeFile(1:end-4)).segSE;
                app.curThresh = app.Allthresh(ismember(app.Allcases,app.activeFile),:,:);
                app.curWaves = app.Allwaves(ismember(app.Allcases,app.activeFile),:);

                % Plot function
                for eleI = 1:length(app.numELEc)
                    updatePlot(app , eleI , 1)
                end
                
                app.NUMsegs.Text = '1';
                app.curSeg = app.NUMsegs.Text;
                
                % Check if already assessed
                if ismember(app.activeFile , app.SpikeInfo.DepthID)
                    % turn off switches
                    
                    % Create message 
                    uialert(app.figure1, 'This depth already assessed','Complete')
                    next_file_Callback(app)
                end

            end
        end


        function updatePlot(app , ENUM , figSeg)

            esing = num2str(ENUM);
            esingE = ['E',esing];

            yDatAll = app.curEledat{ENUM,:};
            segIND = app.curALLSegs(str2double(app.NUMsegs.Text),:);
            
            % Added check on 6/23/2021
            if segIND(2) > length(yDatAll)
                y2plot = yDatAll(segIND(1):length(yDatAll));
            else
                y2plot = yDatAll(segIND(1):segIND(2));
            end
              
            colorS = [1 , 0 , 0;
                0 , 0 , 1;
                0 , 0.49 , 0];

            app.eleColor.(esingE) = colorS(ENUM,:);

            chthresh = 3:6;
            getThr = find(app.thrshSD.Value == chthresh);
               
            app.threshL = app.curThresh(ENUM,2,getThr);
            app.YLIMi = app.curThresh(ENUM,4,getThr);

            switch ENUM
                case 1
                    plot(app.ele1win, y2plot,'Color',...
                        app.eleColor.(esingE))
                    yline(app.ele1win,[app.threshL -(app.threshL)],...
                        'Color','k','LineStyle','--','LineWidth',1.5)
                    ylim(app.ele1win,[-(app.YLIMi) app.YLIMi]);
                    xlim(app.ele1win,[1 numel(y2plot)])
                case 2
                    plot(app.ele2win, y2plot,'Color',...
                        app.eleColor.(esingE))
                    yline(app.ele2win,[app.threshL -(app.threshL)],...
                        'Color','k','LineStyle','--','LineWidth',1.5)
                    ylim(app.ele2win,[-(app.YLIMi) app.YLIMi]);
                    xlim(app.ele2win,[1 numel(y2plot)])
                case 3
                    plot(app.ele3win, y2plot,'Color',...
                        app.eleColor.(esingE))
                    yline(app.ele3win,[app.threshL -(app.threshL)],...
                        'Color','k','LineStyle','--','LineWidth',1.5)
                    ylim(app.ele3win,[-(app.YLIMi) app.YLIMi]);
                    xlim(app.ele3win,[1 numel(y2plot)])
            end


            % Plot Waveforms above baseline

            if figSeg == 1

                app.waVEForms = app.curWaves{ENUM,getThr};
                
                if size(app.waVEForms,2) < 50
                    app.waVEForms2plot = app.waVEForms;
                else
                    totlNwaves = size(app.waVEForms,2);
                    waveSeq = randperm(totlNwaves,50);
                    app.waVEForms2plot = app.waVEForms(:,waveSeq);
                end


                % PLOT data
                minPeakval = min(min(app.waVEForms2plot));
                minYval = minPeakval + (minPeakval * 0.1);
                maxPeakval = max(max(app.waVEForms2plot));
                maxYval = maxPeakval + (maxPeakval * 0.1);

                % Maybe interpolate for visualization ???????

                if isempty(app.waVEForms)
                    switch ENUM
                        case 1
                            cla(app.peak1)
                            title(app.peak1,'0','Color',app.eleColor.(esingE))
                        case 2
                            cla(app.peak2)
                            title(app.peak2,'0','Color',app.eleColor.(esingE))
                        case 3
                            cla(app.peak3)
                            title(app.peak3,'0','Color',app.eleColor.(esingE))
                    end

                else
                    switch ENUM
                        case 1
                            plot(app.peak1, app.waVEForms2plot, 'Color', app.eleColor.(esingE));
                            xlim(app.peak1,[1 size(app.waVEForms,1)]);
                            ylim(app.peak1,[minYval maxYval]);
                            titleStr = num2str(size(app.waVEForms,2));
                            title(app.peak1,titleStr,'Color',app.eleColor.(esingE));
                        case 2
                            plot(app.peak2, app.waVEForms2plot, 'Color', app.eleColor.(esingE));
                            xlim(app.peak2,[1 size(app.waVEForms,1)]);
                            ylim(app.peak2,[minYval maxYval]);
                            titleStr = num2str(size(app.waVEForms,2));
                            title(app.peak2,titleStr,'Color',app.eleColor.(esingE));
                        case 3
                            plot(app.peak3, app.waVEForms2plot, 'Color', app.eleColor.(esingE));
                            xlim(app.peak3,[1 size(app.waVEForms,1)]);
                            ylim(app.peak3,[minYval maxYval]);
                            titleStr = num2str(size(app.waVEForms,2));
                            title(app.peak3,titleStr,'Color',app.eleColor.(esingE));
                    end

                end

            end

        end


        function updateThresh(app)

            % Recompute Threshold

            for eleI = 1:length(app.numELEc)
                updatePlot(app , eleI , 1)
            end

            app.zoomOUT.Value = 0;
            updateZoom(app);

        end

        function updateZoom(app)
            if app.zState
                for i = 1:length(app.numELEc)

                    yDatAll = app.curEledat{i,:};
                    yDatAZ = yDatAll(yDatAll ~= 0);

                    colorS = [1 , 0 , 0;
                        0 , 0 , 1;
                        0 , 0.49 , 0];
                    
                    switch i
                        case 1
                            plot(app.ele1win,yDatAZ,'Color',colorS(i,:))
                            xlim(app.ele1win,[1 length(yDatAZ)])
                            yline(app.ele1win,[app.threshL -(app.threshL)],...
                                'Color','k','LineStyle','--','LineWidth',1.5)
                            ylim(app.ele1win,[-(app.YLIMi) app.YLIMi]);
                        case 2
                            plot(app.ele2win,yDatAZ,'Color',colorS(i,:))
                            xlim(app.ele2win,[1 length(yDatAZ)])
                            yline(app.ele2win,[app.threshL -(app.threshL)],...
                                'Color','k','LineStyle','--','LineWidth',1.5)
                            ylim(app.ele2win,[-(app.YLIMi) app.YLIMi]);
                        case 3
                            plot(app.ele3win,yDatAZ,'Color',colorS(i,:))
                            xlim(app.ele3win,[1 length(yDatAZ)])
                            yline(app.ele3win,[app.threshL -(app.threshL)],...
                                'Color','k','LineStyle','--','LineWidth',1.5)
                            ylim(app.ele3win,[-(app.YLIMi) app.YLIMi]);
                    end

                end
                % Turn off Segment buttons
                app.seg_back.Enable = 'off';
                app.seg_for.Enable = 'off';
            else
                for i = 1:length(app.numELEc)
                    
                    updatePlot(app , i , 0)
                    
                end

                % Turn on Segment buttons
                app.seg_back.Enable = 'on';
                app.seg_for.Enable = 'on';
            end
        end

        function updateQC(app)

            topFldns = fieldnames(app.SpikeInfo);

            if ~ismember('DepthID',topFldns)
                for ei = 1:length(app.numELEc)
                    hold(app.(['peak',num2str(ei)]), 'on')
                    xlabel(app.(['peak',num2str(ei)]), 'NA')
                    hold(app.(['peak',num2str(ei)]), 'off')
                end
                return
            end

            idFldns = app.SpikeInfo.DepthID;

            if ~ismember(app.activeFile, idFldns)
                for ei = 1:length(app.numELEc)
                    hold(app.(['peak',num2str(ei)]), 'on')
                    xlabel(app.(['peak',num2str(ei)]), 'NA')
                    hold(app.(['peak',num2str(ei)]), 'off')
                end
                return
            end


            for ei = 1:length(app.numELEc)

                hold(app.(['peak',num2str(ei)]), 'on')

                if app.SpikeInfo.DepthIND{ismember(idFldns,app.activeFile)}(ei)
                    xlabi = 'YES';
                else
                    xlabi = 'NO';
                end
                xlabel(app.(['peak',num2str(ei)]), xlabi)
                hold(app.(['peak',num2str(ei)]), 'off')

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.messageTxt.Text = 'Press the ''L'' Button to initiate the program';
            app.curTHRESH = app.thrshSD.Value;
            app.curSeg = app.NUMsegs.Text;
            app.listFiles.Enable = 'off';
        end

        % Clicked callback: loadFolder
        function loadFolder_ClickedCallback(app, event)

            fileCname = ['VC_',sprintf('%X','FileLoc'),'.txt'];
            fileCheck = string(fileCname);

            % Identify location of fileCheck
            [textLocFlag , app.mainFoldLoc] = uigetfile('*.txt');

            while textLocFlag == 0
                [textLocFlag , app.mainFoldLoc] = uigetfile('*.txt');
            end

            textLF = string(textLocFlag);
            if fileCheck ~= textLF
                return
            end
            
            app.loadUIprog = uiprogressdlg(app.figure1,'Title','LOADING Files',...
                'Message','Loading files','Indeterminate','on');
            drawnow

            % rawDataFold = [app.mainFoldLoc , filesep , 'RAW_Ephys_Files', filesep];
            app.fil2spkFold = [app.mainFoldLoc , filesep , 'SpikeDates2Cluster', filesep];
            app.visCdatFold = [app.mainFoldLoc , filesep , 'VisualPrepFiles', filesep];

            % Load location with Spike Files to Cluster - RAW_Ephys_Files
            cd(app.visCdatFold)
            mrdir = dir;
            mrDirFolds1 = {mrdir.name};
            mrDirFoldsC = mrDirFolds1(~ismember(mrDirFolds1,{'.','..','.MATLABDriveTag'}));

            app.listFiles.Items = ['...',mrDirFoldsC];
            
            app.listFiles.Enable = 'on';
            
            close(app.loadUIprog)

        end

        % Button pushed function: next_file
        function next_file_Callback(app, event)
% 
%             tic;
%             tstart = tic;
%             while toc(tstart) < 0.01
            
            app.MOVE = 'forward';
            new_file_process(app);
            updateQC(app)
            % Depth Num
            app.depthVal.Text = app.activeFile;
            app.curFile.Text = num2str(app.activeFileNum);

            % Reset Buttons
            for si = 1:3
                app.(['E',num2str(si),'Switch']).Value = "NO";
            end

            set(app.seg_back,'Enable','on');
            set(app.seg_for,'Enable','on');

        end

        % Button pushed function: seg_back
        function seg_back_Callback(app, event)

            app.curSeg = app.NUMsegs.Text;
            if str2double(app.curSeg) == 1
                app.messageTxt.Text = 'At the LOWEST segment';
                return
            else
                % Update Segment Handle
                app.curSeg = num2str(str2double(app.curSeg) - 1);
                app.NUMsegs.Text = app.curSeg;
                for eleI = 1:length(app.numELEc)
                    updatePlot(app , eleI , 0)
                end
                app.messageTxt.Text = 'Advanced Segment Window BACKWARD';
            end

        end

        % Button pushed function: seg_for
        function seg_for_Callback(app, event)

            app.curSeg = app.NUMsegs.Text;
            if str2double(app.curSeg) == length(app.curALLSegs)
                app.messageTxt.Text = 'At the HIGHEST segment';
                return
            else
                app.curSeg = num2str(str2double(app.curSeg) + 1);
                app.NUMsegs.Text = app.curSeg;
                for eleI = 1:length(app.numELEc)
                    updatePlot(app , eleI , 0)
                end
                app.messageTxt.Text = 'Advanced Segment Window FORWARD';
            end

        end

        % Value changed function: zoomOUT
        function zoomOUT_Callback(app, event)

            app.zState = app.zoomOUT.Value;
            updateZoom(app)

        end

        % Clicked callback: closeProgram
        function closeProgramClicked(app, event)
            figure1CloseRequest(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end

        % Value changed function: thrshSD
        function thrshSDValueChanged(app, event)
            stepThresh = app.thrshSD.Value;
            app.curTHRESH = stepThresh;
            if app.curTHRESH <= 2
                app.thrshSD.Value = 3;
                return
            elseif app.curTHRESH >= 7
                app.thrshSD.Value = 6;
                return
            else
                updateThresh(app)
            end

        end

        % Clicked callback: exporT
        function exporTClicked(app, event)
            
            export_data(app)
        end

        % Value changed function: listFiles
        function listFilesValueChanged(app, event)
            app.vCfile = app.listFiles.Value;
            
            if string(app.vCfile) == "..."
                return
            end
            
            app.fileUIprog = uiprogressdlg(app.figure1,'Title','Setting up File',...
                'Message','Loading selected file','Indeterminate','on');
            drawnow
            
            % Check if file already exists and whether complete
            cd(app.fil2spkFold) 
            
            chckDIR = dir('*.mat');
            matchDir = {chckDIR.name};
            
            previousIndex = contains(matchDir, app.vCfile);
            
            if any(previousIndex)
%                 load(matchDir{previousIndex},'DepthID','DepthIND','fedTable')
%                 app.SpikeInfo.DepthID = DepthID;
%                 app.SpikeInfo.DepthIND = DepthIND;
%                 app.SpikeInfo.fedTable = fedTable;

%                 startfile = sum(cellfun(@(x) ~isempty(x), DepthID, 'UniformOutput', true)) + 1;
                
                % In the future allow for coming back to where left off -
                % for now assume file is complete
                
                uialert(app.figure1 , 'FILE is already PROCESSED!' , 'Select different file!')
                close(app.fileUIprog)
                return
                
                
            else
                startfile = 1;

            end
            
            % Load in mFname list
            cd(app.visCdatFold) 
            load(app.vCfile, 'caseID','surgInfo','elecID','segIDs',...
                'allDAT16','threshInfo','waveForms')
            app.numELEc = unique(elecID);
            app.AllElecs = elecID;
            app.fNames = unique(caseID);
            app.Allcases = caseID;
            app.AllDat = allDAT16;
            app.AllSegs = segIDs;
            app.Allthresh = threshInfo;
            app.Allwaves = waveForms;
            app.surgINFO = surgInfo;
            
            % Save mat file list as cell array in handles
            app.FileTotal = numel(app.fNames);
            app.activeFileNum = startfile;
            app.activeFile = app.fNames{app.activeFileNum};
            
            app.curEledat = app.AllDat(ismember(app.Allcases,app.activeFile),:);
            app.curALLSegs = app.AllSegs.(app.activeFile(1:end-4)).segSE; 
            app.curThresh = app.Allthresh(ismember(app.Allcases,app.activeFile),:,:);
            app.curWaves = app.Allwaves(ismember(app.Allcases,app.activeFile),:);

            app.NUMsegs.Text = '1';
            allFileParts = strsplit(app.vCfile,{'_','.'});

            % Determine set number
            app.setNum = allFileParts{6}(2);
            app.surgNum = allFileParts{5};
            app.caseNum.Text = strjoin(allFileParts(2:4),'_');

            app.SpikeInfo.CaseNum = app.caseNum.Text;
            app.SpikeInfo.DepthID = repmat({''},size(transpose(app.fNames)));

            app.depthVal.Text = app.activeFile;

            % Find absent electrodes
            if surgInfo.numEles == 1
                app.E2Switch.Value = 'YES';
                app.E3Switch.Value = 'YES';
            elseif surgInfo.numEles == 2
                app.E3Switch.Value = 'YES';
            end

            app.seg_back.Enable = 'on';
            app.seg_for.Enable = 'on';
            app.next_file.Enable = 'on';

            app.totFile.Text = num2str(app.FileTotal);
            app.curFile.Text = num2str(startfile);

            % Set up Export data structure
            app.SpikeInfo.CaseNum = app.vCfile;
            app.QCpanel.Enable = 'on';
            app.zoomOUT.Enable = 'on';
            app.seg_back.Enable = 'on';
            app.seg_for.Enable = 'on';

            updateQC(app)

            app.messageTxt.Text = 'Folder Successfully loaded!';
            
            for eleI = 1:length(app.numELEc)
                updatePlot(app , eleI , 1)
            end
            
            app.listFiles.Enable = 'off';
            app.QCpanel.Enable = 'on';
            app.accept.Enable = 'on';
            
            if app.numELEc == 1
                % Clear plots 2 and 3
                cla(app.peak2)
                cla(app.ele2win)
                cla(app.peak3)
                cla(app.ele3win)
            elseif app.numELEc == 2
                % Clear plots 3
                cla(app.peak3)
                cla(app.ele3win)
            end
            
            close(app.fileUIprog)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Visual TRACE';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.loadFolder = uipushtool(app.uitoolbar1);
            app.loadFolder.Tag = 'loadFolder';
            app.loadFolder.ClickedCallback = createCallbackFcn(app, @loadFolder_ClickedCallback, true);
            app.loadFolder.Icon = 'loadFolder_image.png';
            app.closeProgram = uipushtool(app.uitoolbar1);
            app.closeProgram.Tag = 'closeProgram';
            app.closeProgram.ClickedCallback = createCallbackFcn(app, @closeProgramClicked, true);
            app.closeProgram.Icon = 'closeProgram_image.png';
            app.exporT = uipushtool(app.uitoolbar1);
            app.exporT.Visible = 'off';
            app.exporT.ClickedCallback = createCallbackFcn(app, @exporTClicked, true);
            app.exporT.Enable = 'off';
            app.exporT.Icon = 'exportS.png';
            app.RawTracesPanel = uipanel(app.figure1);
            app.RawTracesPanel.Title = 'Raw Traces';
            app.GridLayout = uigridlayout(app.RawTracesPanel);
            app.ele1win = uiaxes(app.GridLayout);
            zlabel(app.ele1win, 'Z')
            app.ele1win.XTick = [];
            app.ele1win.YTick = [];
            app.ele2win = uiaxes(app.GridLayout);
            zlabel(app.ele2win, 'Z')
            app.ele2win.XTick = [];
            app.ele2win.YTick = [];
            app.ele3win = uiaxes(app.GridLayout);
            zlabel(app.ele3win, 'Z')
            app.ele3win.XTick = [];
            app.ele3win.YTick = [];
            app.peak1 = uiaxes(app.GridLayout);
            app.peak1.XTick = [];
            app.peak1.YTick = [];
            app.peak2 = uiaxes(app.GridLayout);
            app.peak2.XTick = [];
            app.peak2.YTick = [];
            app.peak3 = uiaxes(app.GridLayout);
            app.peak3.XTick = [];
            app.peak3.YTick = [];
            app.messageTxt = uilabel(app.figure1);
            app.messageTxt.Tag = 'messageTxt';
            app.messageTxt.Text = 'Program Status';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'out of';
            app.totFile = uilabel(app.figure1);
            app.totFile.Tag = 'totFile';
            app.totFile.Text = '0';
            app.curFile = uilabel(app.figure1);
            app.curFile.Tag = 'curFile';
            app.curFile.Text = '0';
            app.depthVal = uilabel(app.figure1);
            app.depthVal.Tag = 'depthVal';
            app.depthVal.Text = 'AbvTrgt_00_0000.mat';
            app.caseNum = uilabel(app.figure1);
            app.caseNum.Tag = 'caseNum';
            app.caseNum.Text = '01/01/1901';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'DEPTH #';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'CASE #';
            app.FilestosortPanel = uipanel(app.figure1);
            app.FilestosortPanel.Title = 'Files to sort';
            app.listFiles = uilistbox(app.FilestosortPanel);
            app.listFiles.ValueChangedFcn = createCallbackFcn(app, @listFilesValueChanged, true);
            app.zoomOUT = uibutton(app.figure1, 'state');
            app.zoomOUT.ValueChangedFcn = createCallbackFcn(app, @zoomOUT_Callback, true);
            app.zoomOUT.Tag = 'zoomOUT';
            app.zoomOUT.Enable = 'off';
            app.zoomOUT.Text = 'Zoom OUT';
            app.next_file = uibutton(app.figure1, 'push');
            app.next_file.ButtonPushedFcn = createCallbackFcn(app, @next_file_Callback, true);
            app.next_file.Tag = 'next_file';
            app.next_file.Enable = 'off';
            app.next_file.Text = 'File ->';
            app.NUMsegs = uilabel(app.figure1);
            app.NUMsegs.Tag = 'NUMsegs';
            app.NUMsegs.Text = '1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Seg. Num';
            app.seg_for = uibutton(app.figure1, 'push');
            app.seg_for.ButtonPushedFcn = createCallbackFcn(app, @seg_for_Callback, true);
            app.seg_for.Tag = 'seg_for';
            app.seg_for.Enable = 'off';
            app.seg_for.Text = 'Forwd';
            app.seg_back = uibutton(app.figure1, 'push');
            app.seg_back.ButtonPushedFcn = createCallbackFcn(app, @seg_back_Callback, true);
            app.seg_back.Tag = 'seg_back';
            app.seg_back.Enable = 'off';
            app.seg_back.Text = 'Back';
            app.QCpanel = uipanel(app.figure1);
            app.QCpanel.Enable = 'off';
            app.QCpanel.Title = 'Quality Control Check';
            app.E1SwitchLabel = uilabel(app.QCpanel);
            app.E1SwitchLabel.Text = 'E1';
            app.E1Switch = uiswitch(app.QCpanel, 'toggle');
            app.E1Switch.Items = {'NO', 'YES'};
            app.E1Switch.Value = 'NO';
            app.E3SwitchLabel = uilabel(app.QCpanel);
            app.E3SwitchLabel.Text = 'E3';
            app.E3Switch = uiswitch(app.QCpanel, 'toggle');
            app.E3Switch.Items = {'NO', 'YES'};
            app.E3Switch.Value = 'NO';
            app.E2SwitchLabel = uilabel(app.QCpanel);
            app.E2SwitchLabel.Text = 'E2';
            app.E2Switch = uiswitch(app.QCpanel, 'toggle');
            app.E2Switch.Items = {'NO', 'YES'};
            app.E2Switch.Value = 'NO';
            app.SDThreshLabel = uilabel(app.figure1);
            app.SDThreshLabel.Text = 'SD Thresh.';
            app.thrshSD = uispinner(app.figure1);
            app.thrshSD.ValueChangedFcn = createCallbackFcn(app, @thrshSDValueChanged, true);
            app.thrshSD.Value = 4;
            app.accept = uibutton(app.figure1, 'state');
            app.accept.Text = 'ACCEPT ASSESSMENT';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VisualTrace_v06

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end